classdef waveAnalysisApp_v7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        appLogo                        matlab.ui.control.Image
        loadDataButton                 matlab.ui.control.Button
        recordingLengthEditField       matlab.ui.control.NumericEditField
        RecordingLengthEditFieldLabel  matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        kHzLabel                       matlab.ui.control.Label
        aquisitionRateEditField        matlab.ui.control.NumericEditField
        AquisitionRateEditFieldLabel   matlab.ui.control.Label
        fileNameLabel                  matlab.ui.control.Label
        browseFilesButton              matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        rawDataTab                     matlab.ui.container.Tab
        pausePlayButton_rawMovie       matlab.ui.control.StateButton
        rasterplotMarkerSize           matlab.ui.control.NumericEditField
        ColormapRangeLabel_3           matlab.ui.control.Label
        colormapLimitMovie             matlab.ui.control.NumericEditField
        ColormapRangeLabel             matlab.ui.control.Label
        framesPerSecLabel              matlab.ui.control.Label
        frameRateDropDown              matlab.ui.control.DropDown
        FrameRateDropDownLabel         matlab.ui.control.Label
        rawTValueLabel                 matlab.ui.control.Label
        rawTimeSlider                  matlab.ui.control.Slider
        TimeSliderLabel                matlab.ui.control.Label
        UIAxes_rawTraceTracker         matlab.ui.control.UIAxes
        UIAxes_rawMovie                matlab.ui.control.UIAxes
        UIAxes_rasterPlot              matlab.ui.control.UIAxes
        UIAxes_spikeRateTrace          matlab.ui.control.UIAxes
        orientationTab                 matlab.ui.container.Tab
        midlineButton                  matlab.ui.control.Button
        xCoordLabel                    matlab.ui.control.Label
        xCoordsSlider                  matlab.ui.control.Slider
        xcoordinatesSliderLabel        matlab.ui.control.Label
        colormapLimitHeatmap           matlab.ui.control.NumericEditField
        ColormapRangeLabel_2           matlab.ui.control.Label
        plotRawHeatmapButton           matlab.ui.control.Button
        eyeDropDown                    matlab.ui.control.DropDown
        EyeDropDownLabel               matlab.ui.control.Label
        orientationDropDown            matlab.ui.control.DropDown
        VentralLeafOrientationLabel    matlab.ui.control.Label
        UIAxes_rawHeatmap              matlab.ui.control.UIAxes
        burstDetectionTab              matlab.ui.container.Tab
        runbaselineburstdetectionButton  matlab.ui.control.Button
        burstDetVar_isiThresholdDynamic  matlab.ui.control.NumericEditField
        ISIthresholdfromnoactivityLabel_2  matlab.ui.control.Label
        burstDetVar_isiThreshold       matlab.ui.control.NumericEditField
        ISIthresholdfromnoactivityLabel  matlab.ui.control.Label
        baselineSamplesEditField       matlab.ui.control.NumericEditField
        baselinesamplesEditFieldLabel  matlab.ui.control.Label
        IdentifyBaselineButton         matlab.ui.control.Button
        DetectBursts                   matlab.ui.control.Button
        UIAxes_burstsRasterplot        matlab.ui.control.UIAxes
        UIAxes_actualSpikesRasterplot  matlab.ui.control.UIAxes
        UIAxes_burstRateTrace          matlab.ui.control.UIAxes
        UIAxes_spikeRateTrace2         matlab.ui.control.UIAxes
        waveDetectionTab               matlab.ui.container.Tab
        waveDataFileLabel              matlab.ui.control.Label
        uploadWaveDataButton           matlab.ui.control.Button
        pausePlayButton_waveDetection  matlab.ui.control.StateButton
        frameRateDropDown2             matlab.ui.control.DropDown
        FrameRateDropDownLabel_2       matlab.ui.control.Label
        framesPerSecLabel2             matlab.ui.control.Label
        detectionTValueLabel           matlab.ui.control.Label
        detectionTimeSlider            matlab.ui.control.Slider
        detectionProgressPercentLabel  matlab.ui.control.Label
        saveWaveIDButton               matlab.ui.control.Button
        detectionProgress              matlab.ui.control.LinearGauge
        detectionprogressGaugeLabel    matlab.ui.control.Label
        numWavesDetectedValue          matlab.ui.control.Label
        numWavesDetectedLabel          matlab.ui.control.Label
        detectWavesButton              matlab.ui.control.Button
        UIAxes_rawTraceTracker2        matlab.ui.control.UIAxes
        UIAxes_rawMovie2               matlab.ui.control.UIAxes
        UIAxes_detectionTraceTracker   matlab.ui.control.UIAxes
        UIAxes_waveDetectionMovie      matlab.ui.control.UIAxes
        individualWavesTab             matlab.ui.container.Tab
        pausePlayButton_currentWave    matlab.ui.control.StateButton
        plotColorPicker                matlab.ui.control.ColorPicker
        PlotColorLabel                 matlab.ui.control.Label
        analysisProgress               matlab.ui.control.LinearGauge
        analysisprogressLabel          matlab.ui.control.Label
        analysisProgressPercentLabel   matlab.ui.control.Label
        saveWaveAnalysisButton         matlab.ui.control.Button
        waveTimeSlider                 matlab.ui.control.Slider
        analyzeWavesButton             matlab.ui.control.Button
        waveDurLabel                   matlab.ui.control.Label
        waveTValueLabel                matlab.ui.control.Label
        SmoothLevelSpinnerLabel_2      matlab.ui.control.Label
        downSampleSpinner              matlab.ui.control.Spinner
        waveIDSpinner                  matlab.ui.control.Spinner
        propDirPanel                   matlab.ui.container.Panel
        WaveIDSpinnerLabel             matlab.ui.control.Label
        UIAxes_waveFlowField           matlab.ui.control.UIAxes
        UIAxes_waveArea                matlab.ui.control.UIAxes
        UIAxes_waveInitiation          matlab.ui.control.UIAxes
        UIAxes_currentWave             matlab.ui.control.UIAxes
        allWavesTab                    matlab.ui.container.Tab
        SmoothLevelSpinnerLabel        matlab.ui.control.Label
        downSampleSpinner2             matlab.ui.control.Spinner
        midlineValue                   matlab.ui.control.Label
        temporalValue                  matlab.ui.control.Label
        nasalValue                     matlab.ui.control.Label
        allPropDirPanel                matlab.ui.container.Panel
        UIAxes_allIWI                  matlab.ui.control.UIAxes
        UIAxes_allFrequency            matlab.ui.control.UIAxes
        UIAxes_allSpeeds               matlab.ui.control.UIAxes
        UIAxes_allFlowField            matlab.ui.control.UIAxes
        UIAxes_allInitiation           matlab.ui.control.UIAxes
        UIAxes_allArea                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileName
        pathName
        data
        mapping
        numChannels
        uniqueChannels
        spikeRateChannel
        colormapRange
        rawMovieVar
        rawMovieCurrentFrame
        playIcon
        pauseIcon
        midline
        burstMatrix
        uniqueOccurence
        rawWaveData
        waveData
        waveID
        numWaves
        waveDetectionCurrentFrame
        timeVar
        currWaveTimePoints
        currentWaveMat
        currentWaveCurrentFrame
        waveAnalysisResults
        freqPerPixel
        IWIPerPixel
        currentWaveCompPlot
        allWaveCompPlot
        xVectorSums
        yVectorSums
        waveVectorSums
        downsampledFieldX
        downsampledFieldY
        downsampledTotalFieldX
        downsampledTotalFieldY
        meanBaselineISI
        bursts
    end
    
    methods (Access = private)
        
        function [waveAnalysisResults,freqPerPixel,IWIPerPixel] = analyzeWaves(~,waveIDvar,waveDataset,rawMovieVar,timeVar, ...
                                                        analysisProgressBar,midline)
                % initialize some variables                                           
                windowSize = 1;
                NumWaves = length(waveIDvar);

                % initialize compiled results variables
                waveAnalysisResults = struct;
                waveAnalysisResults.perWaveResults = table;
                waveAnalysisResults.summaryResults = table;

                waveAnalysisResults.perWaveResults.waveNumber(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.waveStart(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.waveEnd(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.waveDuration(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.waveAreaMatrix = cell(NumWaves,1);
                waveAnalysisResults.perWaveResults.wavePixels(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.waveArea(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.initiationCoordinates(NumWaves,2) = nan;
                waveAnalysisResults.perWaveResults.initiationRegion(NumWaves) = string;
                waveAnalysisResults.perWaveResults.instantaneousSpeed = cell(NumWaves,1);
                waveAnalysisResults.perWaveResults.waveSpeed(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.waveVectorsX = cell(NumWaves,1);
                waveAnalysisResults.perWaveResults.waveVectorsY = cell(NumWaves,1);
                waveAnalysisResults.perWaveResults.waveVectorSumX(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.waveVectorSumY(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.vectorTheta(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.vectorRho(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.activeNeuronThreshold(NumWaves) = nan;
                waveAnalysisResults.perWaveResults.NND = cell(NumWaves,1);

                waveAnalysisResults.summaryResults.numberOfWaves(1) = NumWaves;
                waveAnalysisResults.summaryResults.waveFrequency(1) = nan;
                waveAnalysisResults.summaryResults.IWI(1) = nan;
                waveAnalysisResults.summaryResults.averageArea(1) = nan;
                waveAnalysisResults.summaryResults.avgInitiationCoords(1,2) = nan;
                waveAnalysisResults.summaryResults.initiationBias = cell(1,1);
                waveAnalysisResults.summaryResults.totalVectorSumsX = cell(1,1);
                waveAnalysisResults.summaryResults.totalVectorSumsY = cell(1,1);

                % preallocate vector matrix for later calculating sums
                tempVecMatX = nan(size(waveDataset,1),size(waveDataset,2),NumWaves);
                tempVecMatY = nan(size(waveDataset,1),size(waveDataset,2),NumWaves);

            % Analyze each wave
                for n = 1:NumWaves
                    analysisProgressBar.Value = round(n/NumWaves*100);
                    pause(0.0001)
                    waveAnalysisResults.perWaveResults.waveNumber(n) = n;
                    currentWave = waveIDvar(n);

                % Wave duration, onset and end
                    waveTempMat = waveDataset; 
                    waveTempMat(waveTempMat~=currentWave) = 0; 
                    sumCoor = sum(waveTempMat,1); % sum to make it 2D
                    sumCoor = sum(sumCoor,2); % sum again to make it 1D (going only in time direction)
                    sumCoor(sumCoor>0) = 1; % make all active pixels = 1
                    waveTimePoints = find(sumCoor==1); % wave duration is the time points where pixels are active
                    waveStart = waveTimePoints(1); 
                    waveEnd = waveTimePoints(end);
                    currWaveMat = waveTempMat(:,:,waveStart:waveEnd);
                    waveAnalysisResults.perWaveResults.waveStart(n) = waveStart;
                    waveAnalysisResults.perWaveResults.waveEnd(n) = waveEnd;
                    waveAnalysisResults.perWaveResults.waveDuration(n) = length(waveTimePoints);

                % Area
                    areaTempMat = waveDataset; % copy data matrix
                    areaTempMat(areaTempMat~=currentWave) = 0; % isolate current wave
                    waveAreaMat = sum(areaTempMat,3); % make it 2D
                    waveAnalysisResults.perWaveResults.waveAreaMatrix{n} = waveAreaMat; % store area matrix data
                    waveAreaMat(waveAreaMat>0) = 1; % make each active pixel = 1
                    wavePix = sum(waveAreaMat(waveAreaMat==1)); % sum of active pixels
                    waveAnalysisResults.perWaveResults.wavePixels(n) = wavePix;
                    waveAnalysisResults.perWaveResults.waveArea(n) = (wavePix*7656.25)/1000000; % convert to microns, then to mm
                
                % Initiation sites
                    onsetCenter = regionprops('table',currWaveMat(:,:,1),'centroid'); % get centroid of first frame = initiation site
                    currWaveInit = onsetCenter.Centroid(~isnan(onsetCenter.Centroid));
                    waveAnalysisResults.perWaveResults.initiationCoordinates(n,:) = currWaveInit;
                    if currWaveInit(1,1) < midline
                        waveAnalysisResults.perWaveResults.initiationRegion(n) = "Temporal";
                    elseif currWaveInit(1,1) > midline
                        waveAnalysisResults.perWaveResults.initiationRegion(n) = "Nasal";
                    else
                        waveAnalysisResults.perWaveResults.initiationRegion(n) = "Midline";
                    end
                    
                % Vector Fields
                    % preallocate centroid matrices
                    centersMatX = nan(size(currWaveMat));
                    centersMatY = nan(size(currWaveMat));

                    % calculate centroids for each pixel of wave over time
                    for t = 1:size(currWaveMat,3)
                        for x = 1:size(currWaveMat,2)
                            for y = 1:size(currWaveMat,1)
                
                                if x <= windowSize
                                   xWin = (1:x+windowSize);
                                elseif x > size(currWaveMat,2)-windowSize
                                   xWin = (x-windowSize:size(currWaveMat,2));
                                else
                                   xWin = (x-windowSize:x+windowSize);
                                end
                
                                if y <= windowSize
                                   yWin = (1:y+windowSize);
                                elseif y > size(currWaveMat,1)-windowSize
                                   yWin = (y-windowSize:size(currWaveMat,1));
                                else
                                   yWin = (y-windowSize:y+windowSize);
                                end
                
                                frameWin = currWaveMat(yWin,xWin,t);
                
                                 if frameWin(:,:) == 0
                                    centersMatX(y,x,t) = nan;
                                    centersMatY(y,x,t) = nan;
                                 else
                                    currentCenter = regionprops('table',frameWin,'centroid');
                                    centersMatX(y,x,t) = currentCenter.Centroid(~isnan(currentCenter.Centroid(:,1)),1);
                                    centersMatY(y,x,t) = currentCenter.Centroid(~isnan(currentCenter.Centroid(:,2)),2);
                                 end
                            end
                        end
                    end
                
                    % calculate diff for each pixel of wave
                    xVectorFrames = diff(centersMatX,[],3);
                    yVectorFrames = diff(centersMatY,[],3);
            
                    % calculate diff for each pixel of wave and store vector matrix data
                    waveVectorsX = flipud(sum(xVectorFrames,3,"omitnan")); % flip both x and y matrices up and down so coordinates align with image
                    waveVectorsY = flipud(sum(yVectorFrames,3,"omitnan"))*-1; % inverse only y matrix so vectors are aligned with image
                    
                    % make array of vectors and sum them
                    waveVecArrayX = waveVectorsX(~isnan(waveVectorsX));
                    waveVecArrayY = waveVectorsY(~isnan(waveVectorsY));
    
                    waveVctrSumX = sum(waveVecArrayX);
                    waveVctrSumY = sum(waveVecArrayY);

                   % store vector data into results variables
                    tempVecMatX(:,:,n) = waveVectorsX;
                    tempVecMatY(:,:,n) = waveVectorsY;
                    waveAnalysisResults.perWaveResults.waveVectorsX{n} = waveVectorsX(:,:);
                    waveAnalysisResults.perWaveResults.waveVectorsY{n} = waveVectorsY(:,:);
                    waveAnalysisResults.perWaveResults.waveVectorSumX(n) = waveVctrSumX;
                    waveAnalysisResults.perWaveResults.waveVectorSumY(n) = waveVctrSumY;
    
                    % convert vectors into polar coordinates and store them
                    [waveAnalysisResults.perWaveResults.vectorTheta(n),waveAnalysisResults.perWaveResults.vectorRho(n)] = cart2pol(waveVctrSumX,waveVctrSumY);

                % Speed
                    revVecFramesX = flip(xVectorFrames,3); % reverse frames
                    revVecFramesY = flip(yVectorFrames,3);
                
                    xWavefronts = diff(revVecFramesX,[],3);
                    yWavefronts = diff(revVecFramesY,[],3);
                
                    wavefrontMags = hypot(xWavefronts,yWavefronts);
                    avgSpeedPerFrame = nan(size(wavefrontMags,3)-1,1);
                    for s = 1:size(wavefrontMags,3)
                        avgPixelsPerSec = mean(wavefrontMags(:,:,s),"all","omitnan");
                        avgSpeedPerFrame(s) = avgPixelsPerSec*87.5; % convert to microns (87.5µm per pixel)
                    end
            
                     waveAnalysisResults.perWaveResults.instantaneousSpeed{n} = avgSpeedPerFrame;
                     waveAnalysisResults.perWaveResults.waveSpeed(n) = mean(avgSpeedPerFrame,"omitnan");

                % Local Synchrony
                    tMid = ((waveEnd-waveStart)/2)+waveStart; % calculate middle time frame for current wave duration 
                        tMid = round(tMid,0,"decimals");
            
                    % threshold active neurons from raw data at each tMid frame
                    partOfWave = find(sum(waveTempMat(:,:,tMid-1:tMid+1),3)>0);
                    midFrames = rawMovieVar(:,:,tMid-1:tMid+1);
                    threshold = mean(midFrames(partOfWave))/2;
                    avgMidFrame = mean(midFrames,3);
                    inactiveNeurons = find(avgMidFrame<=threshold);
                    avgMidFrame(inactiveNeurons) = nan;
            
                    % calculate nearest neighbor distance
                    [xp,yp] = meshgrid(1:44,1:23); 
                    activeInd = find(~isnan(avgMidFrame));
                    x = xp(activeInd);
                    y = yp(activeInd);
                    allPairWiseDiff = pdist([x,y]);
                    squareData = squareform(allPairWiseDiff);
                    squareData(squareData==0) = nan;
                    
                    nnd = min(squareData)'; % nearest neighbor distance for each column (each cell)
                    
                    % store the data
                    waveAnalysisResults.perWaveResults.activeNeuronThreshold(n) = threshold;
                    waveAnalysisResults.perWaveResults.NND{n} = nnd;
                end

                % calculate IWI and frequency
                pixFreq = nan(size(waveDataset,1),size(waveDataset,2));
                pixIWI = nan(size(waveDataset,1),size(waveDataset,2));
                for xx = 1:size(waveDataset,2)
                    for yy = 1:size(waveDataset,1)
            
                        currentCoords = waveDataset(yy,xx,:);
                        currUniqueIDs = unique(currentCoords);
                        currUniqueIDs = currUniqueIDs(~isnan(currUniqueIDs));
                
                        if length(currUniqueIDs) < NumWaves*.1 % skip if barely any waves pass through current channel
                            continue
                        end
                        
                        pixFreq(yy,xx) = length(currUniqueIDs)/timeVar;
            
                        starts = nan(length(currUniqueIDs),1);
                        for u = 1:length(currUniqueIDs) % get the start frame for each wave that passes through current pixel
                            tempMat = currentCoords;
                            tempMat(tempMat~=currUniqueIDs(u)) = 0;
                            tempMat(tempMat>0) = 1;
                            if tempMat(:,:,1) == 1
                                starts(u) = 1;
                            else
                                diffs = diff(tempMat);
                                starts(u) = find(diffs==1,1,"first");
                            end
                        end
                        
                        starts = sort(starts); % sort so the diff is calculated correctly
                
                        pixIWI = diff(starts); % calc IWI
                        pixIWI = abs(pixIWI);
                        avgChIWI = mean(pixIWI);
                        pixIWI(yy,xx) = avgChIWI;
                    end
                end
                
                % average frequency and IWI
                pixFreq = reshape(pixFreq,[numel(pixFreq),1]);
                pixFreq(isnan(pixFreq)) = [];
                % pixFreq(pixFreq==0) = [];
                freqPerPixel = pixFreq*60; % converted to waves/minute
                retAvgFreq = mean(freqPerPixel); 
                waveAnalysisResults.summaryResults.waveFrequency(1) = round(retAvgFreq,3,"significant");
            
                pixIWI = reshape(pixIWI,[numel(pixIWI),1]);
                pixIWI(isnan(pixIWI)) = [];
                pixIWI(pixIWI==0) = [];
                IWIPerPixel = pixIWI/60; % converted to minutes
                retAvgIWI = mean(IWIPerPixel);
                waveAnalysisResults.summaryResults.IWI(1) = round(retAvgIWI,3,"significant");
            
                % calculate average area
                avgArea = mean(waveAnalysisResults.perWaveResults.waveArea); 
                    waveAnalysisResults.summaryResults.averageArea(1) = round(avgArea,3,"significant");

                % calculate average speed
                avgSpeedPerRetina = mean(waveAnalysisResults.perWaveResults.waveSpeed);
                    waveAnalysisResults.summaryResults.averageSpeed(1) = round(avgSpeedPerRetina,3,"significant");

                % calculate average initiation site
                avgInitiation = mean(waveAnalysisResults.perWaveResults.initiationCoordinates,1); 
                    waveAnalysisResults.summaryResults.avgInitiationCoords(1,:) = round(avgInitiation,3,"significant");

                % calculate % of waves that initiate on either side (nasal or temporal bias)
                initBias = table;
                [initBias.sideCounts,initBias.uniqueSides,initBias.sidePercentages] = groupcounts(waveAnalysisResults.perWaveResults.initiationRegion);
                
                waveAnalysisResults.summaryResults.initiationBias{1} = initBias(:,:);
            
                % calculate vector sums of all waves
                totVecSumsX = sum(tempVecMatX,3);
                totVecSumsY = sum(tempVecMatY,3);
                waveAnalysisResults.summaryResults.totalVectorSumsX{1} = totVecSumsX(:,:);
                waveAnalysisResults.summaryResults.totalVectorSumsY{1} = totVecSumsY(:,:);

        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.playIcon = 'icon-mat-play-arrow.png';
            app.pauseIcon = 'icon-mat-pause.png';
            
% Top section of app
            app.browseFilesButton.Tooltip = 'upload file'; 
            app.fileNameLabel.Text = 'Select a file';
            app.fileNameLabel.FontColor = [0.7 0.7 0.7];
            
            app.aquisitionRateEditField.Tooltip = 'number of samples per second';
            app.AquisitionRateEditFieldLabel.FontColor = [0.94,0.94,0.94];
            app.aquisitionRateEditField.Value = 20;

            app.recordingLengthEditField.Tooltip = 'length of recording in seconds';
            app.RecordingLengthEditFieldLabel.FontColor = [0.94,0.94,0.94];

% Raw data tab
            app.UIAxes_spikeRateTrace.Title.String = "Retinal Firing Rate (spikes/s)";
            app.UIAxes_spikeRateTrace.Title.FontSize = 16;

            app.UIAxes_rasterPlot.Title.String = "Firing Rate Per Neuron (spikes/s)";
            app.UIAxes_rasterPlot.Title.FontSize = 16;

            app.rasterplotMarkerSize.Value = 0.2;
            app.rasterplotMarkerSize.Tooltip = 'Type spike data marker size';

            app.frameRateDropDown.Items = {'1','2','10','20','100','200'};
            app.frameRateDropDown.ItemsData = [1,0.5,0.1,0.05,0.01,0.005];
            app.frameRateDropDown.Value = 0.05;
            app.frameRateDropDown.Tooltip = 'Select video speed';

            app.colormapLimitMovie.Value = 2;
            app.colormapLimitMovie.Tooltip = 'Type upper limits for video colormap';

            app.UIAxes_rawMovie.Title.String = "Original Data Video";
            app.UIAxes_rawMovie.Title.FontSize = 16;
            app.UIAxes_rawMovie.Box = "on";
            app.UIAxes_rawMovie.XTick = [];
            app.UIAxes_rawMovie.YTick = [];

            colorbar(app.UIAxes_rawMovie,"Limits",[0 app.colormapLimitMovie.Value])

            app.UIAxes_rawTraceTracker.XTick = [];
            app.UIAxes_rawTraceTracker.YTick = [];
            
            app.rawTValueLabel.Text = 't = ____ s';

            app.rawMovieCurrentFrame = 1;
            app.pausePlayButton_rawMovie.Icon = app.playIcon;

% Orientation tab
            app.orientationDropDown.Items = {'Down','Up','Left','Right'};
            app.orientationDropDown.ItemsData = [1,2,3,4]; 
            app.orientationDropDown.Value = 1;

            app.eyeDropDown.Items = {'Left','Right'};
            app.eyeDropDown.ItemsData = [1,2];
            app.eyeDropDown.Value = 1;

            app.colormapLimitHeatmap.Value = 1;
            app.colormapLimitHeatmap.Tooltip = 'Type upper limits for colormap';

            app.UIAxes_rawHeatmap.Title.String = "Raw Data Heatmap";
            app.UIAxes_rawHeatmap.Title.FontSize = 16;
            app.UIAxes_rawHeatmap.Box = "on";
            app.UIAxes_rawHeatmap.Subtitle.String = "Dorsal";
            app.UIAxes_rawHeatmap.Subtitle.FontSize = 14;
            xlabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14)
            app.UIAxes_rawHeatmap.XTick = [];
            yyaxis(app.UIAxes_rawHeatmap,"left")
            app.UIAxes_rawHeatmap.YColor = 'k';
            ylabel(app.UIAxes_rawHeatmap,"Temporal","FontSize",14);
            app.UIAxes_rawHeatmap.YTick = [];
            yyaxis(app.UIAxes_rawHeatmap,"right")
            app.UIAxes_rawHeatmap.YColor = 'k';
            ylabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14,"Rotation",270);
            app.UIAxes_rawHeatmap.YTick = [];

            colorbar(app.UIAxes_rawHeatmap,"Limits",[0 app.colormapLimitHeatmap.Value])

            app.xCoordLabel.Text = "x-coordinate = ";
            app.xCoordLabel.FontSize = 16;
            app.xCoordLabel.BackgroundColor = "none";

% Burst detection tab
            app.UIAxes_spikeRateTrace2.Title.String = "Firing Rate (spikes/s)";
            app.UIAxes_spikeRateTrace2.Title.FontSize = 16;

            app.UIAxes_actualSpikesRasterplot.Title.String = "Raw Data";
            app.UIAxes_actualSpikesRasterplot.Title.FontSize = 16;

            app.UIAxes_burstRateTrace.Title.String = "Burst Rate (bursts/s)";
            app.UIAxes_burstRateTrace.Title.FontSize = 16;

            app.UIAxes_burstsRasterplot.Title.String = "Burst Data";
            app.UIAxes_burstsRasterplot.Title.FontSize = 16;

% Wave detection tab            
            app.UIAxes_rawMovie2.Title.String = "Raw Data";
            app.UIAxes_rawMovie2.Title.FontSize = 16;
            app.UIAxes_rawMovie2.Color = [0.15 0.15 0.15];
            app.UIAxes_rawMovie2.Box = "on";
            app.UIAxes_rawMovie2.XTick = [];
            app.UIAxes_rawMovie2.YTick = [];

            app.UIAxes_waveDetectionMovie.Title.String = "Detected Waves";
            app.UIAxes_waveDetectionMovie.Title.FontSize = 16;
            app.UIAxes_waveDetectionMovie.Color = [0.15 0.15 0.15];
            app.UIAxes_waveDetectionMovie.Box = "on";
            app.UIAxes_waveDetectionMovie.XTick = [];
            app.UIAxes_waveDetectionMovie.YTick = [];

            app.UIAxes_rawTraceTracker2.XTick = [];
            app.UIAxes_rawTraceTracker2.YTick = [];
            app.UIAxes_detectionTraceTracker.XTick = [];
            app.UIAxes_detectionTraceTracker.YTick = [];

            app.frameRateDropDown2.Items = {'1','2','10','20','100','200'};
            app.frameRateDropDown2.ItemsData = [1,0.5,0.1,0.05,0.01,0.005];
            app.frameRateDropDown2.Value = 0.05;
            app.frameRateDropDown2.Tooltip = 'Select video speed';

            app.detectionTValueLabel.Text = 't = ____ s';

            app.numWavesDetectedValue.Text = '0';

            app.uploadWaveDataButton.Tooltip = 'upload a previously saved wave data variable';
            app.detectWavesButton.Tooltip = 'detect waves and create a wave data variable';
            app.saveWaveIDButton.Tooltip = 'save wave data variable as a .mat file';

            app.waveDetectionCurrentFrame = 1;
            app.pausePlayButton_waveDetection.Icon = app.playIcon;

% Individual waves tab
            app.waveIDSpinner.Value = 0;

            app.plotColorPicker.Value = [0.30,0.75,0.93];

            app.waveTValueLabel.Text = 't = ____ s';
            app.waveDurLabel.Text = 'wave duration =';

            app.downSampleSpinner.Limits = [1,15];
            app.downSampleSpinner.Value = 1;

            app.UIAxes_currentWave.Title.String = 'Current Wave';
            app.UIAxes_currentWave.Title.FontSize = 16;
            app.UIAxes_currentWave.Color = [0.15 0.15 0.15];
            app.UIAxes_currentWave.Box = "on";
            app.UIAxes_currentWave.XTick = [];
            app.UIAxes_currentWave.YTick = [];

            app.UIAxes_waveArea.Title.String = "Area";
            app.UIAxes_waveArea.Title.FontSize = 16;
            app.UIAxes_waveArea.Color = [0.15 0.15 0.15];
            app.UIAxes_waveArea.Box = "on";
            app.UIAxes_waveArea.XTick = [];
            app.UIAxes_waveArea.YTick = [];

            app.UIAxes_waveInitiation.Title.String = "Initiation Site";
            app.UIAxes_waveInitiation.Title.FontSize = 16;
            app.UIAxes_waveInitiation.Color = [0.15 0.15 0.15];
            app.UIAxes_waveInitiation.Box = "on";
            app.UIAxes_waveInitiation.XTick = [];
            app.UIAxes_waveInitiation.YTick = [];

            app.UIAxes_waveFlowField.Title.String = "Wave Flow Field";
            app.UIAxes_waveFlowField.Title.FontSize = 16;
            app.UIAxes_waveFlowField.Color = [0.15 0.15 0.15];
            app.UIAxes_waveFlowField.Box = "on";
            app.UIAxes_waveFlowField.XTick = [];
            app.UIAxes_waveFlowField.YTick = [];

            app.propDirPanel.BorderType = "none";
            app.propDirPanel.Title = '';
            app.currentWaveCompPlot = polaraxes(app.propDirPanel);
            app.currentWaveCompPlot.Title.String = "Propagation Direction";
            app.currentWaveCompPlot.Title.FontSize = 12;
            thetaticks(app.currentWaveCompPlot,[0 90 180 270])
            thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})

            app.saveWaveAnalysisButton.Tooltip = 'save wave analysis results as a .mat file';

            app.currentWaveCurrentFrame = 1;
            app.pausePlayButton_currentWave.Icon = app.playIcon;

% All waves tab            
            app.downSampleSpinner2.Limits = [1,15];
            app.downSampleSpinner2.Value = 1;

            app.UIAxes_allArea.Title.String = "Wave Area";
            app.UIAxes_allArea.Title.FontSize = 16;
            app.UIAxes_allArea.XLabel.String = "waves";
            app.UIAxes_allArea.YLabel.String = "mm²";
            app.UIAxes_allArea.XTick = [];
            app.UIAxes_allArea.YTick = [];

            app.UIAxes_allSpeeds.Title.String = "Wave Speeds";
            app.UIAxes_allSpeeds.Title.FontSize = 16;
            app.UIAxes_allSpeeds.XLabel.String = "waves";
            app.UIAxes_allSpeeds.YLabel.String = "µm/s";
            app.UIAxes_allSpeeds.XTick = [];
            app.UIAxes_allSpeeds.YTick = [];

            app.UIAxes_allFrequency.Title.String = "Wave Frequency Per Active Pixel";
            app.UIAxes_allFrequency.Title.FontSize = 16;
            app.UIAxes_allFrequency.XLabel.String = "active pixels";
            app.UIAxes_allFrequency.YLabel.String = "waves/min";
            app.UIAxes_allFrequency.XTick = [];
            app.UIAxes_allFrequency.YTick = [];

            app.UIAxes_allIWI.Title.String = "Inter-Wave Interval Per Active Pixel";
            app.UIAxes_allIWI.Title.FontSize = 16;
            app.UIAxes_allIWI.XLabel.String = "active pixels";
            app.UIAxes_allIWI.YLabel.String = "minutes";
            app.UIAxes_allIWI.XTick = [];
            app.UIAxes_allIWI.YTick = [];

            app.UIAxes_allInitiation.Title.String = "Wave Initiation Site";
            app.UIAxes_allInitiation.Title.FontSize = 16;
            app.UIAxes_allInitiation.Color = [0.15 0.15 0.15];
            app.UIAxes_allInitiation.Box = "on";
            app.UIAxes_allInitiation.XTick = [];
            app.UIAxes_allInitiation.YTick = [];
            
            app.nasalValue.Text = "";
            app.temporalValue.Text = "";
            app.midlineValue.Text = "";

            app.UIAxes_allFlowField.Title.String = "Vector Flow Field";
            app.UIAxes_allFlowField.Title.FontSize = 16;
            app.UIAxes_allFlowField.Color = [0.15 0.15 0.15];
            app.UIAxes_allFlowField.Box = "on";
            app.UIAxes_allFlowField.XTick = [];
            app.UIAxes_allFlowField.YTick = [];
            
            app.allPropDirPanel.BorderType = "none";
            app.allPropDirPanel.Title = '';
            app.allWaveCompPlot = polaraxes(app.allPropDirPanel);
            app.allWaveCompPlot.Title.String = "Propagation Direction";
            app.allWaveCompPlot.Title.FontSize = 12;
            thetaticks(app.allWaveCompPlot,[0 90 180 270])
            thetaticklabels(app.allWaveCompPlot,{'N','D','T','V'})


        end

        % Button pushed function: browseFilesButton
        function browseFilesButtonPushed(app, event)
            [app.fileName, app.pathName] = uigetfile('*.h5');

            cd(string(app.pathName)) % set current path
            app.fileNameLabel.Text = string(app.fileName);
            app.fileNameLabel.FontColor = [0 0 0];
            figure(app.UIFigure) % forces app window to stay visible and not move to back
        end

        % Button pushed function: loadDataButton
        function loadDataButtonPushed(app, event)
            % reset orientation drop downs and midline to default (this is for when user uploads another file after already analyzing one)
            app.orientationDropDown.Value = 1;
            app.eyeDropDown.Value = 1;

            app.xCoordLabel.Text = "x-coordinate = ";
            app.xCoordLabel.BackgroundColor = "none";

            % Pull info about length of recording
            app.timeVar = app.recordingLengthEditField.Value;

            % set up time sliders
            app.rawTimeSlider.Limits = [1 app.timeVar];
            app.rawTimeSlider.MajorTicks = 1:app.timeVar*1/10:app.timeVar;
            app.rawTimeSlider.MinorTicks = [];
            app.detectionTimeSlider.Limits = [1 app.timeVar];
            app.detectionTimeSlider.MajorTicks = 1:app.timeVar*1/10:app.timeVar;
            app.detectionTimeSlider.MinorTicks = [];

            % Load and preprocess data
            app.data = h5read(app.fileName,'/data_store/data0000/spikes');
            % Start spikeTime at zero and convert into seconds
            app.data.frameno = double(app.data.frameno - min(app.data.frameno))/(app.aquisitionRateEditField.Value*1000);
            
            % Synch data with mapping
            app.mapping = h5read(app.fileName,'/data_store/data0000/settings/mapping');
            uniqueChs = unique(app.data.channel);
            [uniqueChs,~,ib] = intersect(uniqueChs,app.mapping.channel);
            app.mapping.channel = app.mapping.channel(ib);
            app.mapping.electrode = app.mapping.electrode(ib);
            app.mapping.x = app.mapping.x(ib);
            app.mapping.y = app.mapping.y(ib);

            % get rid of non mapping channels from data
            numUniqueChannelsInData = unique(app.data.channel);
            for n = 1:length(numUniqueChannelsInData)
                if ismember(numUniqueChannelsInData(n), uniqueChs) == 0
                    chInd = find(app.data.channel == numUniqueChannelsInData(n));
                    app.data.amplitude(chInd) = [];
                    app.data.channel(chInd) = [];
                    app.data.frameno(chInd) = [];
                end
            end
            
            app.numChannels = length(uniqueChs);
            app.uniqueChannels = uniqueChs;

            %Calc a spikeRate matrix and a burst logical matrix
            app.spikeRateChannel = nan(app.numChannels,app.timeVar);
            burstMat = nan(app.numChannels,app.timeVar);
            
            for n = 1:app.numChannels
                currentChannel = uniqueChs(n);
                spikeTimesDoubleChannel = app.data.frameno(app.data.channel==currentChannel);
                app.spikeRateChannel(n,:) = histcounts(spikeTimesDoubleChannel, 0:1:app.timeVar);
                burstMat(n,:) = app.spikeRateChannel(n,:)>2;

            end 
            
            plot(app.UIAxes_spikeRateTrace,sum(app.spikeRateChannel,1),'k','LineWidth',1)
            ylim(app.UIAxes_spikeRateTrace,[0 max(sum(app.spikeRateChannel,1))])
            xlim(app.UIAxes_spikeRateTrace,[0 app.timeVar])
            app.UIAxes_spikeRateTrace.XTickMode = "auto";
            app.UIAxes_spikeRateTrace.YTickMode = "auto";

            plot(app.UIAxes_spikeRateTrace2,sum(app.spikeRateChannel,1),'k','LineWidth',1)
            xlim(app.UIAxes_spikeRateTrace2,[0 app.timeVar])
            app.UIAxes_spikeRateTrace2.XTickMode = "auto";
            app.UIAxes_spikeRateTrace2.YTickMode = "auto";

            scatter(app.UIAxes_rasterPlot,app.data.frameno,app.data.channel,app.rasterplotMarkerSize.Value,'k.')
            ylim(app.UIAxes_rasterPlot,[0 max(app.numChannels)])
            xlim(app.UIAxes_rasterPlot,[0 app.timeVar])
            set(app.UIAxes_rasterPlot, 'YDir','reverse')
            ylim(app.UIAxes_rasterPlot,[0 max(app.numChannels)])
            xlim(app.UIAxes_rasterPlot,[0 app.timeVar])
            app.UIAxes_rasterPlot.XTickMode = "auto";
            app.UIAxes_rasterPlot.YTickMode = "auto";

            scatter(app.UIAxes_actualSpikesRasterplot,app.data.frameno,app.data.channel,app.rasterplotMarkerSize.Value,'.')
            ylim(app.UIAxes_actualSpikesRasterplot,[0 max(app.numChannels)])
            xlim(app.UIAxes_actualSpikesRasterplot,[0 app.timeVar])
            set(app.UIAxes_actualSpikesRasterplot, 'YDir','reverse')

            % Find the unique Xs and Ys
            numUniqueXs = length(unique(app.mapping.x));
            numUniqueYs = length(unique(app.mapping.y));

            app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
 

            for i = 1:app.numChannels
                currentX = app.mapping.x(i);
                currentY = app.mapping.y(i);
                indX = find(currentX==unique(app.mapping.x));
                indY = find(currentY==unique(app.mapping.y));
                app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
            end

            % set up x-coordinates slider and line on orientation tab and name default midline value
            app.xCoordsSlider.Limits = [1 size(app.rawMovieVar,2)];
            app.xCoordsSlider.MajorTicks = 1:1:size(app.rawMovieVar,2);
            app.xCoordsSlider.MinorTicks = [];
            app.xCoordsSlider.Value = size(app.rawMovieVar,2)/2;
            app.xCoordLabel.Text = "x-coordinate = " + num2str(app.xCoordsSlider.Value);
            xline(app.UIAxes_rawHeatmap,size(app.rawMovieVar,2)/2,"LineWidth",2,"Color",'k')
            app.midline = size(app.rawMovieVar,2)/2;
            
            %Look at ret data to determine wave signal and tonic FR
            avgAcrossNpoints = 15;
            
            retData = histcounts(app.data.frameno,1:1:app.timeVar);
            [hipks,hilocs] = findpeaks(retData,'MinPeakDistance',20); %Find largest peaks
            [~,hisortID] = sort(hipks,'descend'); %sort largest to smallest
            [lowpks,lowlocs] = findpeaks(-retData,'MinPeakDistance',20); %Find largest peaks
            [~,lowsortID] = sort(lowpks,'descend'); %sort largest to smallest
            
            loData = mean(app.spikeRateChannel(:,lowlocs(lowsortID(1:avgAcrossNpoints))),2);
            hiData = mean(app.spikeRateChannel(:,hilocs(hisortID(1:avgAcrossNpoints))),2);
            
            % channelThreshold = ((hiData-loData)./3)+loData;
            channelThreshold = loData-5*std(loData);
            
            burstMatrixPerNeuron = app.spikeRateChannel > channelThreshold & app.spikeRateChannel > 2;
            
            app.burstMatrix = burstMatrixPerNeuron;  

            plot(app.UIAxes_burstRateTrace,sum(app.spikeRateChannel,1),'k','LineWidth',1)
            xlim(app.UIAxes_spikeRateTrace2,[0 app.timeVar])
            app.UIAxes_spikeRateTrace2.XTickMode = "auto";
            app.UIAxes_spikeRateTrace2.YTickMode = "auto";


        end

        % Value changed function: rasterplotMarkerSize
        function rasterplotMarkerSizeValueChanged(app, event)
            [~,sortedChannels] = sort(sum(app.spikeRateChannel,2));
            colormap(app.UIAxes_rasterPlot,flipud(gray))
            imagesc(app.UIAxes_rasterPlot,app.spikeRateChannel(sortedChannels,:))
            clim(app.UIAxes_rasterPlot,[0 app.rasterplotMarkerSize.Value]);
            ylim(app.UIAxes_rasterPlot,[0 max(app.numChannels)])
            xlim(app.UIAxes_rasterPlot,[0 app.timeVar])
        end

        % Value changed function: pausePlayButton_rawMovie
        function pausePlayButton_rawMovieValueChanged(app, event)
           
            if app.pausePlayButton_rawMovie.Value == 0
                app.pausePlayButton_rawMovie.Icon = app.playIcon;
            else
                app.pausePlayButton_rawMovie.Icon = app.pauseIcon;
            end

            app.UIAxes_rawMovie.Color = 'none';
            app.UIAxes_rawMovie.Box = "off";
            app.UIAxes_rawMovie.XAxis.Color = 'none';
            app.UIAxes_rawMovie.YAxis.Color = 'none';
    
            colormap(app.UIAxes_rawMovie,"parula");
            c = colorbar(app.UIAxes_rawMovie,"Limits",[0 app.colormapLimitMovie.Value]);
                c.Label.String = 'spikes/s';
                c.FontSize = 12;
            
            for i = app.rawMovieCurrentFrame:app.timeVar 
                app.rawMovieCurrentFrame = i;
                waitfor(app.pausePlayButton_rawMovie,'Value',1)
                imagesc(app.UIAxes_rawMovie,app.rawMovieVar(:,:,i));
                clim(app.UIAxes_rawMovie,[0 app.colormapLimitMovie.Value]);
                plot(app.UIAxes_rawTraceTracker,sum(app.spikeRateChannel,1))
                xlim(app.UIAxes_rawTraceTracker,[0 app.timeVar])
                hold(app.UIAxes_rawTraceTracker,"on")
                xline(app.UIAxes_rawTraceTracker,i)
                hold(app.UIAxes_rawTraceTracker,"off")
                app.rawTValueLabel.Text = "t = " + num2str(i) + " s";
                app.rawTimeSlider.Value = i;
                pause(app.frameRateDropDown.Value)

                if app.rawMovieCurrentFrame == app.timeVar
                    app.pausePlayButton_rawMovie.Value = 0;
                end
            end                        
            
        end

        % Value changing function: rawTimeSlider
        function rawTimeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.rawMovieCurrentFrame = changingValue;
            app.rawTValueLabel.Text = "t = " + num2str(changingValue) + " s";
            imagesc(app.UIAxes_rawMovie,app.rawMovieVar(:,:,changingValue))
            clim(app.UIAxes_rawMovie,[0 app.colormapLimitMovie.Value]);
            plot(app.UIAxes_rawTraceTracker,sum(app.spikeRateChannel,1))
            xlim(app.UIAxes_rawTraceTracker,[0 app.timeVar])
            hold(app.UIAxes_rawTraceTracker,"on")
            xline(app.UIAxes_rawTraceTracker,changingValue)
            hold(app.UIAxes_rawTraceTracker,"off")           
        end

        % Value changed function: colormapLimitMovie
        function colormapLimitMovieValueChanged(app, event)
            c = colorbar(app.UIAxes_rawMovie,"Limits",[0 app.colormapLimitMovie.Value]);
                c.Label.String = 'average spikes/s';
                c.FontSize = 12;
            clim(app.UIAxes_rawHeatmap,[0 app.colormapLimitHeatmap.Value]);
        end

        % Value changed function: orientationDropDown
        function orientationDropDownValueChanged(app, event)
            if app.orientationDropDown.Value == 1 % remake movie var in case user flips matrix and then tries to go back to original (selects dorsal down and then selects ventral down)
                numUniqueXs = length(unique(app.mapping.x));
                numUniqueYs = length(unique(app.mapping.y));
                app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                for i = 1:app.numChannels
                    currentX = app.mapping.x(i);
                    currentY = app.mapping.y(i);
                    indX = find(currentX==unique(app.mapping.x));
                    indY = find(currentY==unique(app.mapping.y));
                    app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                end

                if app.eyeDropDown.Value == 1 % remake movie var in case user flips matrix and then tries to go back to original (selects right and then selects left)
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                elseif app.eyeDropDown.Value == 2 
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                end
                
                % align figure directions
                app.UIAxes_rawHeatmap.Subtitle.String = "Dorsal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Temporal","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'N','D','T','V'})

            elseif app.orientationDropDown.Value == 2
                if app.eyeDropDown.Value == 1
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                    app.rawMovieVar = flipud(app.rawMovieVar);
                elseif app.eyeDropDown.Value == 2 
                    app.rawMovieVar = flipud(app.rawMovieVar);
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                end

                % align figure directions
                app.UIAxes_rawHeatmap.Subtitle.String = "Dorsal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Temporal","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'N','D','T','V'})

            elseif app.orientationDropDown.Value == 3
                numUniqueXs = length(unique(app.mapping.x));
                numUniqueYs = length(unique(app.mapping.y));
                app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                for i = 1:app.numChannels
                    currentX = app.mapping.x(i);
                    currentY = app.mapping.y(i);
                    indX = find(currentX==unique(app.mapping.x));
                    indY = find(currentY==unique(app.mapping.y));
                    app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                end

                if app.eyeDropDown.Value == 1 
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                elseif app.eyeDropDown.Value == 2 
                    app.rawMovieVar = flipud(app.rawMovieVar);
                end

                % align figure directions   
                app.UIAxes_rawHeatmap.Subtitle.String = "Temporal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Dorsal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'D','T','V','N'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'D','T','V','N'})

            elseif app.orientationDropDown.Value == 4
                if app.eyeDropDown.Value == 1
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                    app.rawMovieVar = flipud(app.rawMovieVar);
                elseif app.eyeDropDown.Value == 2 
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                end

                % align figure directions   
                app.UIAxes_rawHeatmap.Subtitle.String = "Temporal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Dorsal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'D','T','V','N'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'D','T','V','N'})
            end
        end

        % Value changed function: eyeDropDown
        function eyeDropDownValueChanged(app, event)
            if app.orientationDropDown.Value == 1 % remake movie var in case user flips matrix and then tries to go back to original (selects dorsal down and then selects ventral down)
                numUniqueXs = length(unique(app.mapping.x));
                numUniqueYs = length(unique(app.mapping.y));
                app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                for i = 1:app.numChannels
                    currentX = app.mapping.x(i);
                    currentY = app.mapping.y(i);
                    indX = find(currentX==unique(app.mapping.x));
                    indY = find(currentY==unique(app.mapping.y));
                    app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                end

                if app.eyeDropDown.Value == 1 % remake movie var in case user flips matrix and then tries to go back to original (selects right and then selects left)
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                elseif app.eyeDropDown.Value == 2 
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                end
                
                % align figure directions
                app.UIAxes_rawHeatmap.Subtitle.String = "Dorsal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Temporal","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'N','D','T','V'})

            elseif app.orientationDropDown.Value == 2
                if app.eyeDropDown.Value == 1
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                    app.rawMovieVar = flipud(app.rawMovieVar);
                elseif app.eyeDropDown.Value == 2 
                    app.rawMovieVar = flipud(app.rawMovieVar);
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                end

                % align figure directions
                app.UIAxes_rawHeatmap.Subtitle.String = "Dorsal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Temporal","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'N','D','T','V'})

            elseif app.orientationDropDown.Value == 3
                numUniqueXs = length(unique(app.mapping.x));
                numUniqueYs = length(unique(app.mapping.y));
                app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                for i = 1:app.numChannels
                    currentX = app.mapping.x(i);
                    currentY = app.mapping.y(i);
                    indX = find(currentX==unique(app.mapping.x));
                    indY = find(currentY==unique(app.mapping.y));
                    app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                end

                if app.eyeDropDown.Value == 1 
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                elseif app.eyeDropDown.Value == 2 
                    app.rawMovieVar = flipud(app.rawMovieVar);
                end

                % align figure directions   
                app.UIAxes_rawHeatmap.Subtitle.String = "Temporal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Dorsal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'D','T','V','N'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'D','T','V','N'})

            elseif app.orientationDropDown.Value == 4
                if app.eyeDropDown.Value == 1
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                    app.rawMovieVar = flipud(app.rawMovieVar);
                elseif app.eyeDropDown.Value == 2 
                    numUniqueXs = length(unique(app.mapping.x));
                    numUniqueYs = length(unique(app.mapping.y));
                    app.rawMovieVar = nan(numUniqueYs,numUniqueXs,app.timeVar);
                    for i = 1:app.numChannels
                        currentX = app.mapping.x(i);
                        currentY = app.mapping.y(i);
                        indX = find(currentX==unique(app.mapping.x));
                        indY = find(currentY==unique(app.mapping.y));
                        app.rawMovieVar(indY,indX,:) = app.spikeRateChannel(i,:);
                    end
                    app.rawMovieVar = fliplr(app.rawMovieVar);
                end

                % align figure directions   
                app.UIAxes_rawHeatmap.Subtitle.String = "Temporal";
                app.UIAxes_rawHeatmap.Subtitle.FontSize = 15;
                xlabel(app.UIAxes_rawHeatmap,"Nasal","FontSize",14)
                app.UIAxes_rawHeatmap.XTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"left")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Ventral","FontSize",14);
                app.UIAxes_rawHeatmap.YTick = [];
                yyaxis(app.UIAxes_rawHeatmap,"right")
                app.UIAxes_rawHeatmap.YColor = 'k';
                ylabel(app.UIAxes_rawHeatmap,"Dorsal","FontSize",14,"Rotation",270);
                app.UIAxes_rawHeatmap.YTick = [];

                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'D','T','V','N'})

                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'D','T','V','N'})
            end
        end

        % Button pushed function: plotRawHeatmapButton
        function plotRawHeatmapButtonPushed(app, event)
            % calculate average activity and plot heatmap on orientation tab
            app.UIAxes_rawHeatmap.Color = [0.15 0.15 0.15];

            colormap(app.UIAxes_rawHeatmap,"parula")
            c = colorbar(app.UIAxes_rawHeatmap,"Limits",[0 app.colormapLimitHeatmap.Value]);
                c.Label.String = 'average spikes/s';
                c.FontSize = 12;
            
            imagesc(app.UIAxes_rawHeatmap,mean(app.rawMovieVar,3))
            clim(app.UIAxes_rawHeatmap,[0 app.colormapLimitHeatmap.Value]);
            xline(app.UIAxes_rawHeatmap,size(app.rawMovieVar,2)/2,"LineWidth",2,"Color",'k')

        end

        % Value changing function: xCoordsSlider
        function xCoordsSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.xCoordLabel.Text = "x-coordinate = " + num2str(changingValue);
            app.xCoordLabel.BackgroundColor = "none";
            imagesc(app.UIAxes_rawHeatmap,mean(app.rawMovieVar,3))
            xline(app.UIAxes_rawHeatmap,changingValue,"LineWidth",2,"Color",'k')
        end

        % Button pushed function: midlineButton
        function midlineButtonPushed(app, event)
            app.midline = app.xCoordsSlider.Value;
            app.waveAnalysisResults.waveInitiation.Midline = app.midline;
            app.xCoordLabel.BackgroundColor = [0.7 0.7 0.7];
        end

        % Value changed function: colormapLimitHeatmap
        function colormapLimitHeatmapValueChanged(app, event)
            c = colorbar(app.UIAxes_rawHeatmap,"Limits",[0 app.colormapLimitHeatmap.Value]);
                c.Label.String = 'average spikes/s';
                c.FontSize = 12;
            imagesc(app.UIAxes_rawHeatmap,mean(app.rawMovieVar,3))
            clim(app.UIAxes_rawHeatmap,[0 app.colormapLimitHeatmap.Value]);
            xline(app.UIAxes_rawHeatmap,app.xCoordsSlider.Value,"LineWidth",2,"Color",'k')
        end

        % Button pushed function: uploadWaveDataButton
        function uploadWaveDataButtonPushed(app, event)
            [waveDataFile, waveDataPath] = uigetfile('*.mat');

            cd(string(waveDataPath)) % set current path
            app.waveData = load(string(waveDataFile));
            app.waveData = struct2array(app.waveData);

            app.waveDataFileLabel.Text = string(waveDataFile);
            app.waveDataFileLabel.FontColor = [0 0 0];
            figure(app.UIFigure) % forces app window to stay visible and not move to back    

            app.waveID = unique(app.waveData(~isnan(app.waveData)));
            app.numWaves = length(app.waveID);
            app.numWavesDetectedValue.Text = num2str(app.numWaves);
            
        end

        % Button pushed function: detectWavesButton
        function detectWavesButtonPushed(app, event)
% Wave detection
            x = ones(3,3,5);
            x(:,1,:) = -1;
            x(:,2,:) = 0;
            x(:,3,:) = 1;
            y = ones(3,3,5);
            y(1,:,:) = -1;
            y(2,:,:) = 0;
            y(3,:,:) = 1;
            z = ones(3,3,5);
            z(:,:,1) = -2;
            z(:,:,2) = -1;
            z(:,:,3) = 0;
            z(:,:,4) = 1;
            z(:,:,5) = 2;

            winSizes = size(z);
            winElements = winSizes(1)*winSizes(2)*winSizes(3);
            
            % turn rasterplots into xyt matrix
            uniqueXs = unique(app.mapping.x);
            uniqueYs = unique(app.mapping.y);
            numXs = length(uniqueXs);
            numYs = length(uniqueYs);
            
            burstMov = nan(numYs,numXs,app.timeVar);
            
            for n = 1:app.numChannels
                currentX = app.mapping.x(n);
                currentY = app.mapping.y(n);
            
                xInd = find(currentX == uniqueXs);
                yInd = find(currentY == uniqueYs);
            
                burstMov(yInd,xInd,:) = app.bursts(n,:);
            end

            isPartOfWave = 5; % Threshold for how many neighbors must have act to be part of a wave
            waveIDcounter = 1; % ID to be given to pixels that are part of the same wave
            
            % Pre allocate waveID
            waveMatrix = nan(size(burstMov));
            
            % Wave detection
            for t = 1:app.timeVar % Go through time
                if floor(t/app.timeVar*10) == t/(app.timeVar/10)
                    app.detectionProgress.Value = round(t/app.timeVar*100);
                    pause(0.0001)
                end
                for xx = 2:numXs-1 % Go through x dim
                    for yy = 2:numYs-1 % Go through y dim
                        if burstMov(yy,xx,t) == 1 % if pixel has activity
                            if isnan(waveMatrix(yy,xx,t)) % and if it is not yet assigned to a wave
                                
                                listCoords = [yy,xx,t]; % Start a list of coordinates starting with current pixel x,y,t
                                wCounter = 1; % While counter that keeps track of how many times we've gone through while loop
                                numUncheckedNb = 1; % Check to see how many neighbors left, ends the while loop if this ever reaches 0
                                while numUncheckedNb > 0

                                     if listCoords(wCounter,1) < 2 || listCoords(wCounter,1) > 22 || ...
                                             listCoords(wCounter,2) < 2 || listCoords(wCounter,2) > 43 || ...
                                             listCoords(wCounter,3) < 3 || listCoords(wCounter,3) > app.timeVar-2 % Check for edge pixels and skips them (could fix this in future version)
            
                                        wCounter = wCounter+1;
                                        numUncheckedNb = size(listCoords,1)-wCounter;
                                        continue 
                                     end
                                     tempMat = burstMov(listCoords(wCounter,1)-1:listCoords(wCounter,1)+1,listCoords(wCounter,2)-1:listCoords(wCounter,2)+1,listCoords(wCounter,3)-2:listCoords(wCounter,3)+2); %Get a grid of activity centered on current pixel
                                     if sum(tempMat,'all') > isPartOfWave % if sum of activity breaches threshold
                                        
                                        % Next few lines are matrix math to quickly ID
                                        % coordinates of active pixels
                                        tempMat(tempMat == 0) = nan;
                                        tempX = tempMat.*x;
                                        tempX = reshape(tempX,[winElements,1]);
                                        tempX(isnan(tempX)) = [];
                                        tempY = tempMat.*y;
                                        tempY = reshape(tempY,[winElements,1]);
                                        tempY(isnan(tempY)) = [];
                                        tempZ = tempMat.*z;
                                        tempZ = reshape(tempZ,[winElements,1]);
                                        tempZ(isnan(tempZ)) = [];
                                        
                                        listCoords = unique([listCoords;[listCoords(wCounter,1)+tempY, listCoords(wCounter,2)+tempX, listCoords(wCounter,3)+tempZ]],'rows','stable'); %Add above coordinates to the ongoing list of active pixels within putative wave
                                     end
                                     numUncheckedNb = size(listCoords,1)-wCounter; %Do we have neighboring pixels that we have not checked yet?
                                     wCounter = wCounter+1; %Add 1 to the while counter
                                end
                                if size(listCoords,1) > 100 %Threshold to be big enough to be considered a wave
                                    for mm = 1:size(listCoords,1) %This loop assigns a cluster of neighboring pixels the same wave ID
                                        waveMatrix(listCoords(mm,1),listCoords(mm,2),listCoords(mm,3)) = waveIDcounter; 
                                    end
                                    waveIDcounter = waveIDcounter+1; %Add 1 to the wave ID
                                end
            
            
                            end
                            
                        end
            
            
                    end
                   
                end
            end
 
            % Analyze waveID            
            [numYs, numXs,recTime] = size(waveMatrix);
            [app.uniqueOccurence, uniqueID] = groupcounts(reshape(waveMatrix,[numYs*numXs*recTime 1]));
            app.uniqueOccurence((isnan(uniqueID))) = [];
            uniqueID((isnan(uniqueID))) = [];
            
            % Filter out small events
            waveNumPix = nan(length(uniqueID),1);
            for n = 1:length(uniqueID)
                currWave = uniqueID(n);
                tempMat = waveMatrix;
                tempMat(tempMat~=currWave) = nan;
                tempMat(tempMat==currWave) = 1;
            
                sumMat = sum(tempMat,3,'omitnan');
                areaMat = sumMat > 0;
                waveNumPix(n) = sum(areaMat,"all",'omitnan');
                if waveNumPix(n) < 15 %Picked 15px b/c that's a natural breakpoint in the data
                    waveMatrix(waveMatrix == currWave) = nan;
                end
            end 

            % Make sure wave data is orientated and aligned with raw data
            if app.orientationDropDown.Value == 1  
                app.rawWaveData = waveMatrix;

                if app.eyeDropDown.Value == 1
                    app.rawWaveData = waveMatrix;
                elseif app.eyeDropDown.Value == 2 
                    app.rawWaveData = fliplr(waveMatrix);
                end

            elseif app.orientationDropDown.Value == 2
                if app.eyeDropDown.Value == 1
                    app.rawWaveData = flipud(waveMatrix);
                elseif app.eyeDropDown.Value == 2 
                    app.rawWaveData = flipud(waveMatrix);
                    app.rawWaveData = fliplr(app.rawWaveData);
                end
            end


            % apply median filter
            app.rawWaveData(isnan(app.rawWaveData)) = 0;
            app.waveData = medfilt3(app.rawWaveData,[3 3 3]);
            app.waveData(app.waveData==0) = nan;

            % get wave IDs and display number of waves value
            app.waveID = unique(app.waveData(~isnan(app.waveData)));
            app.numWaves = length(app.waveID);
            app.numWavesDetectedValue.Text = num2str(app.numWaves);
            
        end

        % Value changed function: pausePlayButton_waveDetection
        function pausePlayButton_waveDetectionValueChanged(app, event)
           
            if app.pausePlayButton_waveDetection.Value == 0
                app.pausePlayButton_waveDetection.Icon = app.playIcon;
            else
                app.pausePlayButton_waveDetection.Icon = app.pauseIcon;
            end

            app.UIAxes_rawMovie2.Color = 'none';
            app.UIAxes_rawMovie2.Box = "off";
            app.UIAxes_rawMovie2.XAxis.Color = 'none';
            app.UIAxes_rawMovie2.YAxis.Color = 'none'; 

            app.UIAxes_waveDetectionMovie.Color = 'none';
            app.UIAxes_waveDetectionMovie.Box = "off";
            app.UIAxes_waveDetectionMovie.XAxis.Color = 'none';
            app.UIAxes_waveDetectionMovie.YAxis.Color = 'none';
            
            colormap(app.UIAxes_rawMovie2,"parula")
            colormap(app.UIAxes_waveDetectionMovie,[0,0,0;rand(length(app.waveID),3)])
            
            for ii = app.waveDetectionCurrentFrame:app.timeVar
                app.waveDetectionCurrentFrame = ii;
                waitfor(app.pausePlayButton_waveDetection,'Value',1)
               
                % play raw movie on the left plot
                imagesc(app.UIAxes_rawMovie2,app.rawMovieVar(:,:,ii));
                clim(app.UIAxes_rawMovie2,[0 app.colormapLimitMovie.Value]);
                plot(app.UIAxes_rawTraceTracker2,sum(app.spikeRateChannel,1))
                xlim(app.UIAxes_rawTraceTracker2,[0 app.timeVar])
                hold(app.UIAxes_rawTraceTracker2,"on")
                xline(app.UIAxes_rawTraceTracker2,ii)
                hold(app.UIAxes_rawTraceTracker2,"off")
                % play wave detection movie on the right plot
                imagesc(app.UIAxes_waveDetectionMovie,app.waveData(:,:,ii))
                clim(app.UIAxes_waveDetectionMovie,[1 length(app.waveID)]);
                plot(app.UIAxes_detectionTraceTracker,sum(app.bursts))
                xlim(app.UIAxes_detectionTraceTracker,[0 app.timeVar])
                hold(app.UIAxes_detectionTraceTracker,"on")
                xline(app.UIAxes_detectionTraceTracker,ii)
                hold(app.UIAxes_detectionTraceTracker,"off")
                app.detectionTValueLabel.Text = "t = " + num2str(ii) + " s";
                app.detectionTimeSlider.Value = ii;
                pause(app.frameRateDropDown2.Value)

                if app.waveDetectionCurrentFrame == app.timeVar
                    app.pausePlayButton_waveDetection.Value = 0;
                end
            end                        
         
        end

        % Value changing function: detectionTimeSlider
        function detectionTimeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.waveDetectionCurrentFrame = changingValue;
            app.detectionTValueLabel.Text = "t = " + num2str(changingValue) + " s";
            % plot current raw data frame
            imagesc(app.UIAxes_rawMovie2,app.rawMovieVar(:,:,changingValue))
            clim(app.UIAxes_rawMovie2,[0 app.colormapLimitMovie.Value]);
            plot(app.UIAxes_rawTraceTracker2,sum(app.spikeRateChannel,1))
            xlim(app.UIAxes_rawTraceTracker2,[0 app.timeVar])
            hold(app.UIAxes_rawTraceTracker2,"on")
            xline(app.UIAxes_rawTraceTracker2,changingValue)
            hold(app.UIAxes_rawTraceTracker2,"off")  
            % plot current wave detection frame
            imagesc(app.UIAxes_waveDetectionMovie,app.waveData(:,:,changingValue))
            plot(app.UIAxes_detectionTraceTracker,sum(app.bursts))
            xlim(app.UIAxes_detectionTraceTracker,[0 app.timeVar])
            hold(app.UIAxes_detectionTraceTracker,"on")
            xline(app.UIAxes_detectionTraceTracker,changingValue)
            hold(app.UIAxes_detectionTraceTracker,"off")
        end

        % Button pushed function: saveWaveIDButton
        function saveWaveIDButtonPushed(app, event)
            waveData = app.waveData;
            uisave('waveData','waveData')
        end

        % Button pushed function: analyzeWavesButton
        function analyzeWavesButtonPushed(app, event)

% Analyze individual waves, store the results into a structure, and display first wave
            [app.waveAnalysisResults,app.freqPerPixel,app.IWIPerPixel] = analyzeWaves( ...
                app,app.waveID,app.waveData,app.rawMovieVar,app.timeVar, ...
                app.analysisProgress,app.midline);    

            app.waveIDSpinner.Limits = [1 length(app.waveID)]; % set spinner limits
            app.waveIDSpinner.Value = 1; % set value                       

            % create a matrix of just the current wave
            waveTempMat = app.waveData;
            waveTempMat(waveTempMat~=app.waveID(app.waveIDSpinner.Value)) = 0;
            app.currentWaveMat = waveTempMat(:,:,app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value));
            app.currWaveTimePoints = app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):1:app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value);

            % set up time slider and fill in labels
            app.waveTimeSlider.Limits = [app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value) app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value)];
            app.waveTimeSlider.MajorTicks = app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):2:app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value);
            app.waveDurLabel.Text = "wave duration = " + num2str(app.waveAnalysisResults.perWaveResults.waveDuration(app.waveIDSpinner.Value)) + " s";

            % make movie of current wave
            app.UIAxes_currentWave.Color = 'none';
            app.UIAxes_currentWave.Box = "off";
            app.UIAxes_currentWave.XAxis.Color = 'none';
            app.UIAxes_currentWave.YAxis.Color = 'none'; 

            map = [0.15 0.15 0.15    
                    app.plotColorPicker.Value];      

            colormap(app.UIAxes_currentWave,map)
            for i = 1:size(app.currentWaveMat,3)
                app.waveTValueLabel.Text = "t = " + num2str(app.currWaveTimePoints(i)) + " s";
                imagesc(app.UIAxes_currentWave,app.currentWaveMat(:,:,i))
                pause(0.1)
            end

            % plot area of current wave
            app.UIAxes_waveArea.Color = 'none';
            app.UIAxes_waveArea.Box = "off";
            app.UIAxes_waveArea.XAxis.Color = 'none';
            app.UIAxes_waveArea.YAxis.Color = 'none'; 

            colormap(app.UIAxes_waveArea,"parula")
            imagesc(app.UIAxes_waveArea,cell2mat(app.waveAnalysisResults.perWaveResults.waveAreaMatrix(app.waveIDSpinner.Value)))
            app.UIAxes_waveArea.Subtitle.String = num2str(app.waveAnalysisResults.perWaveResults.waveArea(app.waveIDSpinner.Value)) + " mm²";
            app.UIAxes_waveArea.Subtitle.FontSize = 14;

            % plot initiation site of current wave
            plot(app.UIAxes_waveInitiation,app.waveAnalysisResults.perWaveResults.initiationCoordinates(app.waveIDSpinner.Value,1),app.waveAnalysisResults.perWaveResults.initiationCoordinates(app.waveIDSpinner.Value,2),'Marker','.','MarkerSize',20,'Color',app.plotColorPicker.Value)
            app.UIAxes_waveInitiation.YDir = "reverse";
            app.UIAxes_waveInitiation.XLim = [0,size(app.waveData,2)];
            app.UIAxes_waveInitiation.YLim = [0,size(app.waveData,1)];

            % plot vector flow field of current wave
            quiver(app.UIAxes_waveFlowField,cell2mat(app.waveAnalysisResults.perWaveResults.waveVectorsX(app.waveIDSpinner.Value)),cell2mat(app.waveAnalysisResults.perWaveResults.waveVectorsY(app.waveIDSpinner.Value)),1.5,'Color',app.plotColorPicker.Value,'LineWidth',1)
            app.UIAxes_waveFlowField.XLim = [0 size(app.waveData,2)];
            app.UIAxes_waveFlowField.YLim = [0 size(app.waveData,1)];
            
            % plot propagation direction
            compassplot(app.currentWaveCompPlot,app.waveAnalysisResults.perWaveResults.vectorTheta(app.waveIDSpinner.Value),app.waveAnalysisResults.perWaveResults.vectorRho(app.waveIDSpinner.Value),"LineWidth",2,"Color",app.plotColorPicker.Value)
            title(app.currentWaveCompPlot,"Propagation Direction","FontSize",12)
            if app.orientationDropDown.Value == 1 || app.orientationDropDown.Value == 2
                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})
            elseif app.orientationDropDown.Value == 3 || app.orientationDropDown.Value == 4
                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'D','T','V','N'}) 
            end

% Plot data for all waves

            % plot area
            x = ones(1,length(app.waveAnalysisResults.perWaveResults.waveArea));
            swarmchart(app.UIAxes_allArea,x,app.waveAnalysisResults.perWaveResults.waveArea,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allArea,"on")
            plot(app.UIAxes_allArea,[0.75 1.25],[app.waveAnalysisResults.summaryResults.averageArea app.waveAnalysisResults.summaryResults.averageArea],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allArea,"off")
            app.UIAxes_allArea.YTickMode = "auto";
            app.UIAxes_allArea.XTickLabel = [];
            app.UIAxes_allArea.Subtitle.String = "Average Wave Area = " + num2str(app.waveAnalysisResults.summaryResults.averageArea) + " mm²";

            % plot speed
            x = ones(1,length(app.waveAnalysisResults.perWaveResults.waveSpeed));
            swarmchart(app.UIAxes_allSpeeds,x,app.waveAnalysisResults.perWaveResults.waveSpeed,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allSpeeds,"on")
            plot(app.UIAxes_allSpeeds,[0.75 1.25],[app.waveAnalysisResults.summaryResults.averageSpeed app.waveAnalysisResults.summaryResults.averageSpeed],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allSpeeds,"off")
            app.UIAxes_allSpeeds.YTickMode = "auto";
            app.UIAxes_allSpeeds.XTickLabel = [];
            app.UIAxes_allSpeeds.Subtitle.String = "Average Wave Speed = " + num2str(app.waveAnalysisResults.summaryResults.averageSpeed) + " µm/s";

            % plot frequency
            x = ones(1,length(app.freqPerPixel));
            swarmchart(app.UIAxes_allFrequency,x,app.freqPerPixel,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allFrequency,"on")
            plot(app.UIAxes_allFrequency,[0.75 1.25],[app.waveAnalysisResults.summaryResults.waveFrequency app.waveAnalysisResults.summaryResults.waveFrequency],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allFrequency,"off")
            app.UIAxes_allFrequency.YTickMode = "auto";
            app.UIAxes_allFrequency.XTickLabel = [];
            app.UIAxes_allFrequency.Subtitle.String = "Average Frequency = " + num2str(app.waveAnalysisResults.summaryResults.waveFrequency) + " waves/min";

            % plot IWI
            x = ones(1,length(app.IWIPerPixel));
            swarmchart(app.UIAxes_allIWI,x,app.IWIPerPixel,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allIWI,"on")
            plot(app.UIAxes_allIWI,[0.75 1.25],[app.waveAnalysisResults.summaryResults.IWI app.waveAnalysisResults.summaryResults.IWI],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allIWI,"off")
            app.UIAxes_allIWI.YTickMode = "auto";
            app.UIAxes_allIWI.XTickLabel = [];
            app.UIAxes_allIWI.Subtitle.String = "Average IWI = " + num2str(app.waveAnalysisResults.summaryResults.IWI) + " min";

            % plot initiation sites
            app.UIAxes_allInitiation.Color = 'none';
            app.UIAxes_allInitiation.Box = "off";
            app.UIAxes_allInitiation.XAxis.Color = 'none';
            app.UIAxes_allInitiation.YAxis.Color = 'none';

            histogram2(app.UIAxes_allInitiation,app.waveAnalysisResults.perWaveResults.initiationCoordinates(:,1),app.waveAnalysisResults.perWaveResults.initiationCoordinates(:,2),0:2:size(app.waveData,2),0:2:size(app.waveData,1),'DisplayStyle','tile','ShowEmptyBins','on')
            set(app.UIAxes_allInitiation,'XTick',[],'YTick',[],'XLim',[0 size(app.waveData,2)],'YLim',[0 size(app.waveData,1)])
            clim(app.UIAxes_allInitiation,[0 5])
            app.UIAxes_allInitiation.Subtitle.String = "Average Initiation Site = (" + num2str(app.waveAnalysisResults.summaryResults.avgInitiationCoords(1)) + "," + num2str(app.waveAnalysisResults.summaryResults.avgInitiationCoords(2)) + ")";

            % display initiation bias percentages
            initiationBiasTable = app.waveAnalysisResults.summaryResults.initiationBias{1};
            app.nasalValue.Text = "Nasal Initiation = " + num2str(initiationBiasTable.sidePercentages(strcmp(initiationBiasTable.uniqueSides,"Nasal"))) + " %";
            app.temporalValue.Text = "Temporal Initiation = " + num2str(initiationBiasTable.sidePercentages(strcmp(initiationBiasTable.uniqueSides,"Temporal"))) + " %";
            app.midlineValue.Text = "Midline Initiation = " + num2str(initiationBiasTable.sidePercentages(strcmp(initiationBiasTable.uniqueSides,"Midline"))) + " %";
            
            % propagation direction / vector flow field 
            quiver(app.UIAxes_allFlowField,cell2mat(app.waveAnalysisResults.summaryResults.totalVectorSumsX),cell2mat(app.waveAnalysisResults.summaryResults.totalVectorSumsY),1,'Color',app.plotColorPicker.Value,'LineWidth',1)
            app.UIAxes_allFlowField.XLim = [0 size(app.waveData,2)];
            app.UIAxes_allFlowField.YLim = [0 size(app.waveData,1)];
            
            % plot propagation direction
            polarhistogram(app.allWaveCompPlot,app.waveAnalysisResults.perWaveResults.vectorTheta(:),16,'FaceColor',app.plotColorPicker.Value)
            title(app.allWaveCompPlot,"Propagation Direction","FontSize",12) 
            if app.orientationDropDown.Value == 1 || app.orientationDropDown.Value == 2
                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'N','D','T','V'})
            elseif app.orientationDropDown.Value == 3 || app.orientationDropDown.Value == 4
                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'D','T','V','N'})
            end

        end

        % Callback function: plotColorPicker
        function plotColorPickerValueChanged(app, event)
% replot data for current wave with new color
            % create a matrix of just the current wave
            waveTempMat = app.waveData;
            waveTempMat(waveTempMat~=app.waveID(app.waveIDSpinner.Value)) = 0;
            app.currentWaveMat = waveTempMat(:,:,app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value));
            app.currWaveTimePoints = app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):1:app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value);

            % set up time slider and fill in labels
            app.waveTimeSlider.Limits = [app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value) app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value)];
            app.waveTimeSlider.MajorTicks = app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):2:app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value);
            app.waveDurLabel.Text = "wave duration = " + num2str(app.waveAnalysisResults.perWaveResults.waveDuration(app.waveIDSpinner.Value)) + " s";

            % make movie of current wave
            map = [0.15 0.15 0.15    
                    app.plotColorPicker.Value];    
           
            colormap(app.UIAxes_currentWave,map)
            for i = 1:size(app.currentWaveMat,3)
                app.waveTValueLabel.Text = "t = " + num2str(app.currWaveTimePoints(i)) + " s";
                imagesc(app.UIAxes_currentWave,app.currentWaveMat(:,:,i))
                pause(0.1)
            end

            % plot area of current wave        
            colormap(app.UIAxes_waveArea,"parula")
            imagesc(app.UIAxes_waveArea,cell2mat(app.waveAnalysisResults.perWaveResults.waveAreaMatrix(app.waveIDSpinner.Value)))
            app.UIAxes_waveArea.Subtitle.String = num2str(app.waveAnalysisResults.perWaveResults.waveArea(app.waveIDSpinner.Value)) + " mm²";
            app.UIAxes_waveArea.Subtitle.FontSize = 14;

            % plot initiation site of current wave
            plot(app.UIAxes_waveInitiation,app.waveAnalysisResults.perWaveResults.initiationCoordinates(app.waveIDSpinner.Value,1),app.waveAnalysisResults.perWaveResults.initiationCoordinates(app.waveIDSpinner.Value,2),'Marker','.','MarkerSize',20,'Color',app.plotColorPicker.Value)
            app.UIAxes_waveInitiation.YDir = "reverse";
            app.UIAxes_waveInitiation.XLim = [0,size(app.waveData,2)];
            app.UIAxes_waveInitiation.YLim = [0,size(app.waveData,1)];

            % plot vector flow field of current wave
            if app.downSampleSpinner.Value == 1
                quiver(app.UIAxes_waveFlowField,cell2mat(app.waveAnalysisResults.perWaveResults.waveVectorsX(app.waveIDSpinner.Value)),cell2mat(app.waveAnalysisResults.perWaveResults.waveVectorsY(app.waveIDSpinner.Value)),1.5,'Color',app.plotColorPicker.Value,'LineWidth',1)
                app.UIAxes_waveFlowField.XLim = [0 size(app.waveData,2)];
                app.UIAxes_waveFlowField.YLim = [0 size(app.waveData,1)];
            elseif app.downSampleSpinner.Value >= 2
                quiver(app.UIAxes_waveFlowField,app.downsampledFieldX,app.downsampledFieldY,app.downSampleSpinner.Value,'LineWidth',1,'Color',app.plotColorPicker.Value)
                app.UIAxes_waveFlowField.XLim = [0 size(app.waveData,2)];
                app.UIAxes_waveFlowField.YLim = [0 size(app.waveData,1)];
            end
            
            % plot propagation direction
            compassplot(app.currentWaveCompPlot,app.waveAnalysisResults.perWaveResults.vectorTheta(app.waveIDSpinner.Value),app.waveAnalysisResults.perWaveResults.vectorRho(app.waveIDSpinner.Value),"LineWidth",2,"Color",app.plotColorPicker.Value)
            title(app.currentWaveCompPlot,"Propagation Direction","FontSize",12)
            if app.orientationDropDown.Value == 1 || app.orientationDropDown.Value == 2
                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})
            elseif app.orientationDropDown.Value == 3 || app.orientationDropDown.Value == 4
                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'D','T','V','N'}) 
            end

% replot data for all waves with new color
            % plot area
            x = ones(1,length(app.waveAnalysisResults.perWaveResults.waveArea));
            swarmchart(app.UIAxes_allArea,x,app.waveAnalysisResults.perWaveResults.waveArea,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allArea,"on")
            plot(app.UIAxes_allArea,[0.75 1.25],[app.waveAnalysisResults.summaryResults.averageArea app.waveAnalysisResults.summaryResults.averageArea],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allArea,"off")
            app.UIAxes_allArea.YTickMode = "auto";
            app.UIAxes_allArea.XTickLabel = [];
            app.UIAxes_allArea.Subtitle.String = "Average Wave Area = " + num2str(app.waveAnalysisResults.summaryResults.averageArea) + " mm²";

            % plot speed
            x = ones(1,length(app.waveAnalysisResults.perWaveResults.waveSpeed));
            swarmchart(app.UIAxes_allSpeeds,x,app.waveAnalysisResults.perWaveResults.waveSpeed,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allSpeeds,"on")
            plot(app.UIAxes_allSpeeds,[0.75 1.25],[app.waveAnalysisResults.summaryResults.averageSpeed app.waveAnalysisResults.summaryResults.averageSpeed],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allSpeeds,"off")
            app.UIAxes_allSpeeds.YTickMode = "auto";
            app.UIAxes_allSpeeds.XTickLabel = [];
            app.UIAxes_allSpeeds.Subtitle.String = "Average Wave Speed = " + num2str(app.waveAnalysisResults.summaryResults.averageSpeed) + " µm/s";

            % plot frequency
            x = ones(1,length(app.freqPerPixel));
            swarmchart(app.UIAxes_allFrequency,x,app.freqPerPixel,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allFrequency,"on")
            plot(app.UIAxes_allFrequency,[0.75 1.25],[app.waveAnalysisResults.summaryResults.waveFrequency app.waveAnalysisResults.summaryResults.waveFrequency],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allFrequency,"off")
            app.UIAxes_allFrequency.YTickMode = "auto";
            app.UIAxes_allFrequency.XTickLabel = [];
            app.UIAxes_allFrequency.Subtitle.String = "Average Frequency = " + num2str(app.waveAnalysisResults.summaryResults.waveFrequency) + " waves/min";

            % plot IWI
            x = ones(1,length(app.IWIPerPixel));
            swarmchart(app.UIAxes_allIWI,x,app.IWIPerPixel,20,'k','filled','MarkerFaceAlpha',0.6,'MarkerEdgeColor','w')
            hold(app.UIAxes_allIWI,"on")
            plot(app.UIAxes_allIWI,[0.75 1.25],[app.waveAnalysisResults.summaryResults.IWI app.waveAnalysisResults.summaryResults.IWI],'Color',app.plotColorPicker.Value,'LineWidth',1)
            hold(app.UIAxes_allIWI,"off")
            app.UIAxes_allIWI.YTickMode = "auto";
            app.UIAxes_allIWI.XTickLabel = [];
            app.UIAxes_allIWI.Subtitle.String = "Average IWI = " + num2str(app.waveAnalysisResults.summaryResults.IWI) + " min";

            % plot initiation sites
            histogram2(app.UIAxes_allInitiation,app.waveAnalysisResults.perWaveResults.initiationCoordinates(:,1),app.waveAnalysisResults.perWaveResults.initiationCoordinates(:,2),0:2:size(app.waveData,2),0:2:size(app.waveData,1),'DisplayStyle','tile','ShowEmptyBins','on')
            set(app.UIAxes_allInitiation,'XTick',[],'YTick',[],'XLim',[0 size(app.waveData,2)],'YLim',[0 size(app.waveData,1)])
            clim(app.UIAxes_allInitiation,[0 5])
            app.UIAxes_allInitiation.Subtitle.String = "Average Initiation Site = (" + num2str(app.waveAnalysisResults.summaryResults.avgInitiationCoords(1)) + "," + num2str(app.waveAnalysisResults.summaryResults.avgInitiationCoords(2)) + ")";

            % display initiation bias percentages
            initiationBiasTable = app.waveAnalysisResults.summaryResults.initiationBias{1};
            app.nasalValue.Text = "Nasal Initiation = " + num2str(initiationBiasTable.sidePercentages(strcmp(initiationBiasTable.uniqueSides,"Nasal"))) + " %";
            app.temporalValue.Text = "Temporal Initiation = " + num2str(initiationBiasTable.sidePercentages(strcmp(initiationBiasTable.uniqueSides,"Temporal"))) + " %";
            app.midlineValue.Text = "Midline Initiation = " + num2str(initiationBiasTable.sidePercentages(strcmp(initiationBiasTable.uniqueSides,"Midline"))) + " %";
            
            % propagation direction / vector flow field 
            if app.downSampleSpinner2.Value == 1
                quiver(app.UIAxes_allFlowField,cell2mat(app.waveAnalysisResults.summaryResults.totalVectorSumsX),cell2mat(app.waveAnalysisResults.summaryResults.totalVectorSumsY),1,'Color',app.plotColorPicker.Value,'LineWidth',1)
                app.UIAxes_allFlowField.XLim = [0 size(app.waveData,2)];
                app.UIAxes_allFlowField.YLim = [0 size(app.waveData,1)];
            elseif app.downSampleSpinner2.Value >= 2
                quiver(app.UIAxes_allFlowField,app.downsampledTotalFieldX,app.downsampledTotalFieldY,app.downSampleSpinner2.Value,'LineWidth',1,'Color',app.plotColorPicker.Value)
                app.UIAxes_allFlowField.XLim = [0 size(app.waveData,2)];
                app.UIAxes_allFlowField.YLim = [0 size(app.waveData,1)];
            end
           
            % plot overall propagation direction
            compassplot(app.allWaveCompPlot,app.waveAnalysisResults.perWaveResults.vectorTheta(:),app.waveAnalysisResults.perWaveResults.vectorRho(:),"LineWidth",2,"Color",app.plotColorPicker.Value)
            title(app.allWaveCompPlot,"Propagation Direction","FontSize",12) 
            if app.orientationDropDown.Value == 1 || app.orientationDropDown.Value == 2
                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'N','D','T','V'})
            elseif app.orientationDropDown.Value == 3 || app.orientationDropDown.Value == 4
                thetaticks(app.allWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.allWaveCompPlot,{'D','T','V','N'})
            end
            
        end

        % Value changed function: waveIDSpinner
        function waveIDSpinnerValueChanged(app, event)

            % create a matrix of just the current wave
            waveTempMat = app.waveData;
            waveTempMat(waveTempMat~=app.waveID(app.waveIDSpinner.Value)) = 0;
            app.currentWaveMat = waveTempMat(:,:,app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value));
            app.currWaveTimePoints = app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):1:app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value);

            % set up time slider and fill in labels
            app.waveTimeSlider.Limits = [app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value) app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value)];
            app.waveTimeSlider.MajorTicks = app.waveAnalysisResults.perWaveResults.waveStart(app.waveIDSpinner.Value):2:app.waveAnalysisResults.perWaveResults.waveEnd(app.waveIDSpinner.Value);
            app.waveDurLabel.Text = "wave duration = " + num2str(app.waveAnalysisResults.perWaveResults.waveDuration(app.waveIDSpinner.Value)) + " s";

            % make movie of current wave
            map = [0.15 0.15 0.15    
                    app.plotColorPicker.Value];    
           
            colormap(app.UIAxes_currentWave,map)
            for i = 1:size(app.currentWaveMat,3)
                app.waveTValueLabel.Text = "t = " + num2str(app.currWaveTimePoints(i)) + " s";
                imagesc(app.UIAxes_currentWave,app.currentWaveMat(:,:,i))
                pause(0.1)
            end

            % plot area of current wave        
            colormap(app.UIAxes_waveArea,"parula")
            imagesc(app.UIAxes_waveArea,cell2mat(app.waveAnalysisResults.perWaveResults.waveAreaMatrix(app.waveIDSpinner.Value)))
            app.UIAxes_waveArea.Subtitle.String = num2str(app.waveAnalysisResults.perWaveResults.waveArea(app.waveIDSpinner.Value)) + " mm²";
            app.UIAxes_waveArea.Subtitle.FontSize = 14;

            % plot initiation site of current wave
            plot(app.UIAxes_waveInitiation,app.waveAnalysisResults.perWaveResults.initiationCoordinates(app.waveIDSpinner.Value,1),app.waveAnalysisResults.perWaveResults.initiationCoordinates(app.waveIDSpinner.Value,2),'Marker','.','MarkerSize',20,'Color',app.plotColorPicker.Value)
            app.UIAxes_waveInitiation.YDir = "reverse";
            app.UIAxes_waveInitiation.XLim = [0,size(app.waveData,2)];
            app.UIAxes_waveInitiation.YLim = [0,size(app.waveData,1)];

            % plot vector flow field of current wave
            quiver(app.UIAxes_waveFlowField,cell2mat(app.waveAnalysisResults.perWaveResults.waveVectorsX(app.waveIDSpinner.Value)),cell2mat(app.waveAnalysisResults.perWaveResults.waveVectorsY(app.waveIDSpinner.Value)),1.5,'Color',app.plotColorPicker.Value,'LineWidth',1)
            app.UIAxes_waveFlowField.XLim = [0 size(app.waveData,2)];
            app.UIAxes_waveFlowField.YLim = [0 size(app.waveData,1)];
            
            % plot propagation direction
            compassplot(app.currentWaveCompPlot,app.waveAnalysisResults.perWaveResults.vectorTheta(app.waveIDSpinner.Value),app.waveAnalysisResults.perWaveResults.vectorRho(app.waveIDSpinner.Value),"LineWidth",2,"Color",app.plotColorPicker.Value)
            title(app.currentWaveCompPlot,"Propagation Direction","FontSize",12)
            if app.orientationDropDown.Value == 1 || app.orientationDropDown.Value == 2
                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'N','D','T','V'})
            elseif app.orientationDropDown.Value == 3 || app.orientationDropDown.Value == 4
                thetaticks(app.currentWaveCompPlot,[0 90 180 270])
                thetaticklabels(app.currentWaveCompPlot,{'D','T','V','N'}) 
            end

        end

        % Value changed function: downSampleSpinner
        function downSampleSpinnerValueChanged(app, event)
            % index out vectors for current wave
            currWaveVectorsX(:,:) = app.waveAnalysisResults.perWaveResults.waveVectorsX{app.waveIDSpinner.Value};
            currWaveVectorsY(:,:) = app.waveAnalysisResults.perWaveResults.waveVectorsY{app.waveIDSpinner.Value};

            % lose a row so num rows will be evenly divisble by blocksize
            currWaveVectorsX_22rows = currWaveVectorsX(1:22,:);
            currWaveVectorsY_22rows = currWaveVectorsY(1:22,:);
                        
            % downsample by binning vector fields
            blocksize = [app.downSampleSpinner.Value app.downSampleSpinner.Value]; % downsampling ratio [h w]
            app.downsampledFieldX = mat2cell(currWaveVectorsX_22rows,repmat(blocksize(1),[1 size(currWaveVectorsX_22rows,1)/blocksize(1)]),...
                repmat(blocksize(2),[1 size(currWaveVectorsX_22rows,2)/blocksize(2)]));
                app.downsampledFieldX = cellfun(@(x) sum(x,'all'),app.downsampledFieldX);
            app.downsampledFieldY = mat2cell(currWaveVectorsY_22rows,repmat(blocksize(1),[1 size(currWaveVectorsY_22rows,1)/blocksize(1)]),...
                repmat(blocksize(2),[1 size(currWaveVectorsY_22rows,2)/blocksize(2)]));
                app.downsampledFieldY = cellfun(@(x) sum(x,'all'),app.downsampledFieldY);

            % plot the data onto flow field figure and compass plot figure
            quiver(app.UIAxes_waveFlowField,app.downsampledFieldX,app.downsampledFieldY,app.downSampleSpinner.Value,'LineWidth',1,'Color',app.plotColorPicker.Value)
            app.UIAxes_waveFlowField.XLim = [0 size(app.waveData,2)/app.downSampleSpinner.Value];
            app.UIAxes_waveFlowField.YLim = [0 size(app.waveData,1)/app.downSampleSpinner.Value];
            
        end

        % Value changed function: pausePlayButton_currentWave
        function pausePlayButton_currentWaveValueChanged(app, event)
            if app.pausePlayButton_currentWave.Value == 0
                app.pausePlayButton_currentWave.Icon = app.playIcon;
            else
                app.pausePlayButton_currentWave.Icon = app.pauseIcon;
            end

            map = [0.15 0.15 0.15    
                    app.plotColorPicker.Value];    
           
            colormap(app.UIAxes_currentWave,map)
            for iii = 1:size(app.currentWaveMat,3)
                app.currentWaveCurrentFrame = iii;
                waitfor(app.pausePlayButton_currentWave,'Value',1)
                app.waveTValueLabel.Text = "t = " + num2str(app.currWaveTimePoints(iii)) + " s";
                imagesc(app.UIAxes_currentWave,app.currentWaveMat(:,:,iii))
                pause(0.1)

                if app.waveDetectionCurrentFrame == app.timeVar
                    app.pausePlayButton_waveDetection.Value = 0;
                end
            end

        end

        % Value changing function: waveTimeSlider
        function waveTimeSliderValueChanging(app, event)
            
            changingValue = round(event.Value);
            app.currentWaveCurrentFrame = changingValue;
            app.waveTValueLabel.Text = "t = " + num2str(changingValue) + " s";
            timeInd = find(app.currWaveTimePoints==changingValue);
            imagesc(app.UIAxes_currentWave,app.currentWaveMat(:,:,timeInd)) 

        end

        % Button pushed function: saveWaveAnalysisButton
        function saveWaveAnalysisButtonPushed(app, event)
            waveAnalysisResults = app.waveAnalysisResults;
            uisave('waveAnalysisResults','waveAnalysisResults')
        end

        % Value changed function: downSampleSpinner2
        function downSampleSpinner2ValueChanged(app, event)
            % index out vectors for all waves and compile into 3D matrix
            for n = 1:app.numWaves
                allWaveVectorsX(:,:,n) = app.waveAnalysisResults.perWaveResults.waveVectorsX{app.waveIDSpinner.Value};
                allWaveVectorsY(:,:,n) = app.waveAnalysisResults.perWaveResults.waveVectorsY{app.waveIDSpinner.Value};
            end

            % sum all vectors in third dimension
            totalVectorSumX = sum(allWaveVectorsX,3);
            totalVectorSumY = sum(allWaveVectorsY,3);

            % lose a row so num rows will be evenly divisble by blocksize
            totalVectorSumX_22rows = totalVectorSumX(1:22,:);
            totalVectorSumY_22rows = totalVectorSumY(1:22,:);
                        
            % downsample by binning vector fields
            blocksize = [app.downSampleSpinner2.Value app.downSampleSpinner2.Value]; % downsampling ratio [h w]
            app.downsampledTotalFieldX = mat2cell(totalVectorSumX_22rows,repmat(blocksize(1),[1 size(totalVectorSumX_22rows,1)/blocksize(1)]),...
                repmat(blocksize(2),[1 size(totalVectorSumX_22rows,2)/blocksize(2)]));
                app.downsampledTotalFieldX = cellfun(@(x) sum(x,'all'),app.downsampledTotalFieldX);
            app.downsampledTotalFieldY = mat2cell(totalVectorSumY_22rows,repmat(blocksize(1),[1 size(totalVectorSumY_22rows,1)/blocksize(1)]),...
                repmat(blocksize(2),[1 size(totalVectorSumY_22rows,2)/blocksize(2)]));
                app.downsampledTotalFieldY = cellfun(@(x) sum(x,'all'),app.downsampledTotalFieldY);

            % plot the data onto flow field figure and compass plot figure
            quiver(app.UIAxes_allFlowField,app.downsampledTotalFieldX,app.downsampledTotalFieldY,app.downSampleSpinner2.Value,'LineWidth',1,'Color',app.plotColorPicker.Value)
            app.UIAxes_allFlowField.XLim = [0 size(app.waveData,2)/app.downSampleSpinner2.Value];
            app.UIAxes_allFlowField.YLim = [0 size(app.waveData,1)/app.downSampleSpinner2.Value];
           
        end

        % Button pushed function: DetectBursts
        function detectBurstsButtonPushed(app, event)

            bursts.time = [];
            bursts.channel = [];
            
            for n = 1:app.numChannels
                % for n = 1:1
                currentChannel = app.uniqueChannels(n);
                
                % get spikes corresponding to the current channel
                channelSpikes = app.data.frameno(app.data.channel == currentChannel);
                
                %calculate inter-spike-interval for this channel
                channelISI = diff(channelSpikes);
                
                %find any ISI that fall within isiThreshold
                if app.meanBaselineISI(n) == inf
                    burstIndices = find(channelISI < app.burstDetVar_isiThreshold.Value);
                else
                    burstIndices = find(channelISI < app.meanBaselineISI(n)/app.burstDetVar_isiThresholdDynamic.Value);
                end
                
                bursts.time = [bursts.time;channelSpikes(burstIndices)];
                bursts.channel = [bursts.channel;repmat(currentChannel,[length(burstIndices),1])];
            
            end
            
            plot(app.UIAxes_burstRateTrace, histcounts(bursts.time,0:1:app.timeVar),'k')
            app.UIAxes_burstRateTrace.XLim = ([0 app.timeVar]);

            scatter(app.UIAxes_burstsRasterplot,bursts.time,bursts.channel,app.rasterplotMarkerSize.Value,"k.")
            xlim(app.UIAxes_burstsRasterplot,[0 app.timeVar])
            ylim(app.UIAxes_burstsRasterplot,[0 app.numChannels])
            set(app.UIAxes_burstsRasterplot,'YDir','reverse')

            
            %calculate burstRate per channel
            burstRateChannel = zeros(app.numChannels,app.timeVar);
            for n = 1:app.numChannels
                currentChannel = app.uniqueChannels(n);
                burstTimesCurrCh = bursts.time(bursts.channel==currentChannel);
                burstRateChannel(n,:) = histcounts(burstTimesCurrCh, 0:1:app.timeVar);
            end 
            
            %binarize BurstRateChannel
            binaryBursts = burstRateChannel > 0;
            
            app.bursts = binaryBursts;


        end

        % Button pushed function: IdentifyBaselineButton
        function IdentifyBaselineButtonPushed(app, event)
             % Identify baseline and ret wave periods
            

            wholeRetSpikeRate = histcounts(app.data.frameno,0:1:app.timeVar);
            [troughs, baselineInd] = findpeaks(wholeRetSpikeRate*(-1),"MinPeakDistance",10,"NPeaks",app.baselineSamplesEditField.Value,'SortStr','descend');
            
            cla(app.UIAxes_spikeRateTrace2,'reset')
            plot(app.UIAxes_spikeRateTrace2,wholeRetSpikeRate,'k','LineWidth',1)
            app.UIAxes_spikeRateTrace2.XLim = [0 app.timeVar];
            app.UIAxes_spikeRateTrace2.XTickMode = "auto";
            app.UIAxes_spikeRateTrace2.YTickMode = "auto";
            xline(app.UIAxes_spikeRateTrace2,baselineInd,'r')

            scatter(app.UIAxes_actualSpikesRasterplot,app.data.frameno,app.data.channel,app.rasterplotMarkerSize.Value,'k.')

            baselineFR = [];
            for n = 1:length(baselineInd)
                baselineFR = [baselineFR, app.spikeRateChannel(:,baselineInd(n)-1:baselineInd(n)+1)];
            end
            meanBaselineFR = mean(baselineFR,2);
            app.meanBaselineISI = 1./meanBaselineFR;


        end

        % Button pushed function: runbaselineburstdetectionButton
        function runbaselineburstdetectionButtonPushed(app, event)
            IdentifyBaselineButtonPushed(app,matlab.ui.eventdata.ButtonPushedData)
            detectBurstsButtonPushed(app,matlab.ui.eventdata.ButtonPushedData)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.rawDataTab = uitab(app.TabGroup);
            app.rawDataTab.Title = 'Raw Data';
            app.UIAxes_spikeRateTrace = uiaxes(app.rawDataTab);
            title(app.UIAxes_spikeRateTrace, 'Firing Rate (spikes/s)')
            app.UIAxes_spikeRateTrace.XTick = [];
            app.UIAxes_spikeRateTrace.YTick = [];
            app.UIAxes_rasterPlot = uiaxes(app.rawDataTab);
            app.UIAxes_rasterPlot.XTick = [];
            app.UIAxes_rasterPlot.YTick = [];
            app.UIAxes_rawMovie = uiaxes(app.rawDataTab);
            title(app.UIAxes_rawMovie, 'Raw Data Video')
            app.UIAxes_rawMovie.XTick = [];
            app.UIAxes_rawMovie.YTick = [];
            app.UIAxes_rawTraceTracker = uiaxes(app.rawDataTab);
            zlabel(app.UIAxes_rawTraceTracker, 'Z')
            app.UIAxes_rawTraceTracker.XTick = [];
            app.UIAxes_rawTraceTracker.YTick = [];
            app.TimeSliderLabel = uilabel(app.rawDataTab);
            app.TimeSliderLabel.Text = 'Time';
            app.rawTimeSlider = uislider(app.rawDataTab);
            app.rawTimeSlider.MajorTicks = [];
            app.rawTimeSlider.ValueChangingFcn = createCallbackFcn(app, @rawTimeSliderValueChanging, true);
            app.rawTimeSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.rawTValueLabel = uilabel(app.rawDataTab);
            app.rawTValueLabel.Text = 't = ______ s';
            app.FrameRateDropDownLabel = uilabel(app.rawDataTab);
            app.FrameRateDropDownLabel.Text = 'Frame Rate';
            app.frameRateDropDown = uidropdown(app.rawDataTab);
            app.frameRateDropDown.Items = {};
            app.frameRateDropDown.Value = {};
            app.framesPerSecLabel = uilabel(app.rawDataTab);
            app.framesPerSecLabel.Text = 'frames/s';
            app.ColormapRangeLabel = uilabel(app.rawDataTab);
            app.ColormapRangeLabel.Text = 'Colormap Limit';
            app.colormapLimitMovie = uieditfield(app.rawDataTab, 'numeric');
            app.colormapLimitMovie.ValueChangedFcn = createCallbackFcn(app, @colormapLimitMovieValueChanged, true);
            app.ColormapRangeLabel_3 = uilabel(app.rawDataTab);
            app.ColormapRangeLabel_3.Text = 'Rasterplot Marker Size';
            app.rasterplotMarkerSize = uieditfield(app.rawDataTab, 'numeric');
            app.rasterplotMarkerSize.ValueChangedFcn = createCallbackFcn(app, @rasterplotMarkerSizeValueChanged, true);
            app.pausePlayButton_rawMovie = uibutton(app.rawDataTab, 'state');
            app.pausePlayButton_rawMovie.ValueChangedFcn = createCallbackFcn(app, @pausePlayButton_rawMovieValueChanged, true);
            app.pausePlayButton_rawMovie.Text = '';
            app.orientationTab = uitab(app.TabGroup);
            app.orientationTab.Title = 'Orientation';
            app.UIAxes_rawHeatmap = uiaxes(app.orientationTab);
            title(app.UIAxes_rawHeatmap, 'Raw Data Heatmap')
            app.UIAxes_rawHeatmap.XTick = [];
            app.UIAxes_rawHeatmap.YTick = [];
            app.VentralLeafOrientationLabel = uilabel(app.orientationTab);
            app.VentralLeafOrientationLabel.Text = 'Ventral Leaf Orientation';
            app.orientationDropDown = uidropdown(app.orientationTab);
            app.orientationDropDown.Items = {};
            app.orientationDropDown.ItemsData = {'1', '2', '3', '4'};
            app.orientationDropDown.ValueChangedFcn = createCallbackFcn(app, @orientationDropDownValueChanged, true);
            app.orientationDropDown.Value = {};
            app.EyeDropDownLabel = uilabel(app.orientationTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.eyeDropDown = uidropdown(app.orientationTab);
            app.eyeDropDown.Items = {};
            app.eyeDropDown.ValueChangedFcn = createCallbackFcn(app, @eyeDropDownValueChanged, true);
            app.eyeDropDown.Value = {};
            app.plotRawHeatmapButton = uibutton(app.orientationTab, 'push');
            app.plotRawHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @plotRawHeatmapButtonPushed, true);
            app.plotRawHeatmapButton.Text = 'Plot';
            app.ColormapRangeLabel_2 = uilabel(app.orientationTab);
            app.ColormapRangeLabel_2.Text = 'Colormap Limit';
            app.colormapLimitHeatmap = uieditfield(app.orientationTab, 'numeric');
            app.colormapLimitHeatmap.ValueChangedFcn = createCallbackFcn(app, @colormapLimitHeatmapValueChanged, true);
            app.xcoordinatesSliderLabel = uilabel(app.orientationTab);
            app.xcoordinatesSliderLabel.Text = 'x-coordinates';
            app.xCoordsSlider = uislider(app.orientationTab);
            app.xCoordsSlider.MajorTicks = [];
            app.xCoordsSlider.ValueChangingFcn = createCallbackFcn(app, @xCoordsSliderValueChanging, true);
            app.xCoordsSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.xCoordLabel = uilabel(app.orientationTab);
            app.xCoordLabel.Text = 'x-coordinate = ';
            app.midlineButton = uibutton(app.orientationTab, 'push');
            app.midlineButton.ButtonPushedFcn = createCallbackFcn(app, @midlineButtonPushed, true);
            app.midlineButton.Text = 'Select Midline';
            app.burstDetectionTab = uitab(app.TabGroup);
            app.burstDetectionTab.Title = 'Burst Detection';
            app.UIAxes_spikeRateTrace2 = uiaxes(app.burstDetectionTab);
            title(app.UIAxes_spikeRateTrace2, 'Firing Rate (spikes/s)')
            app.UIAxes_spikeRateTrace2.XTick = [];
            app.UIAxes_spikeRateTrace2.YTick = [];
            app.UIAxes_burstRateTrace = uiaxes(app.burstDetectionTab);
            title(app.UIAxes_burstRateTrace, 'Burst Rate (bursts/s)')
            app.UIAxes_burstRateTrace.XTick = [];
            app.UIAxes_burstRateTrace.YTick = [];
            app.UIAxes_actualSpikesRasterplot = uiaxes(app.burstDetectionTab);
            title(app.UIAxes_actualSpikesRasterplot, 'RawData')
            app.UIAxes_actualSpikesRasterplot.XTick = [];
            app.UIAxes_actualSpikesRasterplot.YTick = [];
            app.UIAxes_burstsRasterplot = uiaxes(app.burstDetectionTab);
            title(app.UIAxes_burstsRasterplot, 'BurstData')
            app.UIAxes_burstsRasterplot.XTick = [];
            app.UIAxes_burstsRasterplot.YTick = [];
            app.DetectBursts = uibutton(app.burstDetectionTab, 'push');
            app.DetectBursts.ButtonPushedFcn = createCallbackFcn(app, @detectBurstsButtonPushed, true);
            app.DetectBursts.Text = 'Filter out non-wave data';
            app.IdentifyBaselineButton = uibutton(app.burstDetectionTab, 'push');
            app.IdentifyBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyBaselineButtonPushed, true);
            app.IdentifyBaselineButton.Text = 'Identify Baseline';
            app.baselinesamplesEditFieldLabel = uilabel(app.burstDetectionTab);
            app.baselinesamplesEditFieldLabel.Text = '# baseline samples';
            app.baselineSamplesEditField = uieditfield(app.burstDetectionTab, 'numeric');
            app.baselineSamplesEditField.Limits = [0 100];
            app.baselineSamplesEditField.RoundFractionalValues = 'on';
            app.baselineSamplesEditField.Value = 20;
            app.ISIthresholdfromnoactivityLabel = uilabel(app.burstDetectionTab);
            app.ISIthresholdfromnoactivityLabel.Text = 'ISI threshold from no activity (in sec)';
            app.burstDetVar_isiThreshold = uieditfield(app.burstDetectionTab, 'numeric');
            app.burstDetVar_isiThreshold.Limits = [0 10];
            app.burstDetVar_isiThreshold.Value = 1;
            app.ISIthresholdfromnoactivityLabel_2 = uilabel(app.burstDetectionTab);
            app.ISIthresholdfromnoactivityLabel_2.Text = 'ISI threshold from activity (in multi, e,g, 10x)';
            app.burstDetVar_isiThresholdDynamic = uieditfield(app.burstDetectionTab, 'numeric');
            app.burstDetVar_isiThresholdDynamic.Limits = [1 50];
            app.burstDetVar_isiThresholdDynamic.RoundFractionalValues = 'on';
            app.burstDetVar_isiThresholdDynamic.Value = 10;
            app.runbaselineburstdetectionButton = uibutton(app.burstDetectionTab, 'push');
            app.runbaselineburstdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @runbaselineburstdetectionButtonPushed, true);
            app.runbaselineburstdetectionButton.Text = 'run baseline & burst detection';
            app.waveDetectionTab = uitab(app.TabGroup);
            app.waveDetectionTab.Title = 'Wave Detection';
            app.UIAxes_waveDetectionMovie = uiaxes(app.waveDetectionTab);
            title(app.UIAxes_waveDetectionMovie, 'Detected Waves')
            app.UIAxes_waveDetectionMovie.XTick = [];
            app.UIAxes_waveDetectionMovie.YTick = [];
            colormap(app.UIAxes_waveDetectionMovie, 'white')
            app.UIAxes_detectionTraceTracker = uiaxes(app.waveDetectionTab);
            app.UIAxes_detectionTraceTracker.XTick = [];
            app.UIAxes_detectionTraceTracker.YTick = [];
            app.UIAxes_rawMovie2 = uiaxes(app.waveDetectionTab);
            title(app.UIAxes_rawMovie2, 'Raw Data')
            app.UIAxes_rawMovie2.XTick = [];
            app.UIAxes_rawMovie2.YTick = [];
            app.UIAxes_rawTraceTracker2 = uiaxes(app.waveDetectionTab);
            app.UIAxes_rawTraceTracker2.XTick = [];
            app.UIAxes_rawTraceTracker2.YTick = [];
            app.detectWavesButton = uibutton(app.waveDetectionTab, 'push');
            app.detectWavesButton.ButtonPushedFcn = createCallbackFcn(app, @detectWavesButtonPushed, true);
            app.detectWavesButton.Text = 'Detect Waves';
            app.numWavesDetectedLabel = uilabel(app.waveDetectionTab);
            app.numWavesDetectedLabel.Text = 'Number of Waves Detected: ';
            app.numWavesDetectedValue = uilabel(app.waveDetectionTab);
            app.numWavesDetectedValue.Text = '0';
            app.detectionprogressGaugeLabel = uilabel(app.waveDetectionTab);
            app.detectionprogressGaugeLabel.Text = 'detection progress';
            app.detectionProgress = uigauge(app.waveDetectionTab, 'linear');
            app.saveWaveIDButton = uibutton(app.waveDetectionTab, 'push');
            app.saveWaveIDButton.ButtonPushedFcn = createCallbackFcn(app, @saveWaveIDButtonPushed, true);
            app.saveWaveIDButton.Icon = fullfile(pathToMLAPP, 'icon-fa-save.png');
            app.saveWaveIDButton.Text = 'Save As';
            app.detectionProgressPercentLabel = uilabel(app.waveDetectionTab);
            app.detectionProgressPercentLabel.Text = '%';
            app.detectionTimeSlider = uislider(app.waveDetectionTab);
            app.detectionTimeSlider.MajorTicks = [];
            app.detectionTimeSlider.ValueChangingFcn = createCallbackFcn(app, @detectionTimeSliderValueChanging, true);
            app.detectionTimeSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.detectionTValueLabel = uilabel(app.waveDetectionTab);
            app.detectionTValueLabel.Text = 't =';
            app.framesPerSecLabel2 = uilabel(app.waveDetectionTab);
            app.framesPerSecLabel2.Text = 'frames/s';
            app.FrameRateDropDownLabel_2 = uilabel(app.waveDetectionTab);
            app.FrameRateDropDownLabel_2.Text = 'Frame Rate';
            app.frameRateDropDown2 = uidropdown(app.waveDetectionTab);
            app.frameRateDropDown2.Items = {};
            app.frameRateDropDown2.Value = {};
            app.pausePlayButton_waveDetection = uibutton(app.waveDetectionTab, 'state');
            app.pausePlayButton_waveDetection.ValueChangedFcn = createCallbackFcn(app, @pausePlayButton_waveDetectionValueChanged, true);
            app.pausePlayButton_waveDetection.Text = '';
            app.uploadWaveDataButton = uibutton(app.waveDetectionTab, 'push');
            app.uploadWaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @uploadWaveDataButtonPushed, true);
            app.uploadWaveDataButton.Icon = fullfile(pathToMLAPP, 'icon-mat-file-upload.png');
            app.uploadWaveDataButton.Text = 'Upload wave data';
            app.waveDataFileLabel = uilabel(app.waveDetectionTab);
            app.waveDataFileLabel.Text = '';
            app.individualWavesTab = uitab(app.TabGroup);
            app.individualWavesTab.Title = 'Individual Wave Analysis';
            app.UIAxes_currentWave = uiaxes(app.individualWavesTab);
            title(app.UIAxes_currentWave, 'Current Wave')
            app.UIAxes_currentWave.XTick = [];
            app.UIAxes_currentWave.YTick = [];
            app.UIAxes_waveInitiation = uiaxes(app.individualWavesTab);
            title(app.UIAxes_waveInitiation, 'Initiation Site')
            app.UIAxes_waveInitiation.XTick = [];
            app.UIAxes_waveInitiation.YTick = [];
            app.UIAxes_waveArea = uiaxes(app.individualWavesTab);
            title(app.UIAxes_waveArea, 'Area')
            app.UIAxes_waveArea.XTick = [];
            app.UIAxes_waveArea.YTick = [];
            app.UIAxes_waveFlowField = uiaxes(app.individualWavesTab);
            title(app.UIAxes_waveFlowField, 'Wave Flow Field')
            app.UIAxes_waveFlowField.XTick = [];
            app.UIAxes_waveFlowField.YTick = [];
            app.WaveIDSpinnerLabel = uilabel(app.individualWavesTab);
            app.WaveIDSpinnerLabel.Text = 'Wave ID';
            app.propDirPanel = uipanel(app.individualWavesTab);
            app.propDirPanel.Title = 'Panel2';
            app.waveIDSpinner = uispinner(app.individualWavesTab);
            app.waveIDSpinner.ValueChangedFcn = createCallbackFcn(app, @waveIDSpinnerValueChanged, true);
            app.downSampleSpinner = uispinner(app.individualWavesTab);
            app.downSampleSpinner.ValueChangedFcn = createCallbackFcn(app, @downSampleSpinnerValueChanged, true);
            app.SmoothLevelSpinnerLabel_2 = uilabel(app.individualWavesTab);
            app.SmoothLevelSpinnerLabel_2.Text = 'Smooth Level';
            app.waveTValueLabel = uilabel(app.individualWavesTab);
            app.waveTValueLabel.Text = 't =';
            app.waveDurLabel = uilabel(app.individualWavesTab);
            app.waveDurLabel.Text = 'wave duration = ';
            app.analyzeWavesButton = uibutton(app.individualWavesTab, 'push');
            app.analyzeWavesButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeWavesButtonPushed, true);
            app.analyzeWavesButton.Text = 'Analyze Waves';
            app.waveTimeSlider = uislider(app.individualWavesTab);
            app.waveTimeSlider.MajorTicks = [];
            app.waveTimeSlider.ValueChangingFcn = createCallbackFcn(app, @waveTimeSliderValueChanging, true);
            app.waveTimeSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.saveWaveAnalysisButton = uibutton(app.individualWavesTab, 'push');
            app.saveWaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @saveWaveAnalysisButtonPushed, true);
            app.saveWaveAnalysisButton.Icon = fullfile(pathToMLAPP, 'icon-fa-save.png');
            app.saveWaveAnalysisButton.Text = 'Save As';
            app.analysisProgressPercentLabel = uilabel(app.individualWavesTab);
            app.analysisProgressPercentLabel.Text = '%';
            app.analysisprogressLabel = uilabel(app.individualWavesTab);
            app.analysisprogressLabel.Text = 'analysis progress';
            app.analysisProgress = uigauge(app.individualWavesTab, 'linear');
            app.PlotColorLabel = uilabel(app.individualWavesTab);
            app.PlotColorLabel.Text = 'Plot Color';
            app.plotColorPicker = uicolorpicker(app.individualWavesTab);
            app.plotColorPicker.Value = [0.302 0.749 0.9294];
            app.plotColorPicker.ValueChangedFcn = createCallbackFcn(app, @plotColorPickerValueChanged, true);
            app.pausePlayButton_currentWave = uibutton(app.individualWavesTab, 'state');
            app.pausePlayButton_currentWave.ValueChangedFcn = createCallbackFcn(app, @pausePlayButton_currentWaveValueChanged, true);
            app.pausePlayButton_currentWave.Text = '';
            app.allWavesTab = uitab(app.TabGroup);
            app.allWavesTab.Title = 'Population Analysis';
            app.UIAxes_allArea = uiaxes(app.allWavesTab);
            title(app.UIAxes_allArea, 'Wave Areas')
            app.UIAxes_allArea.XTick = [];
            app.UIAxes_allArea.YTick = [];
            app.UIAxes_allInitiation = uiaxes(app.allWavesTab);
            title(app.UIAxes_allInitiation, 'Wave Initiation Sites')
            app.UIAxes_allInitiation.XTick = [];
            app.UIAxes_allInitiation.YTick = [];
            app.UIAxes_allFlowField = uiaxes(app.allWavesTab);
            title(app.UIAxes_allFlowField, 'Vector Flow Field')
            app.UIAxes_allFlowField.XTick = [];
            app.UIAxes_allFlowField.YTick = [];
            app.UIAxes_allSpeeds = uiaxes(app.allWavesTab);
            title(app.UIAxes_allSpeeds, 'Wave Speeds')
            app.UIAxes_allSpeeds.XTick = [];
            app.UIAxes_allSpeeds.YTick = [];
            app.UIAxes_allFrequency = uiaxes(app.allWavesTab);
            title(app.UIAxes_allFrequency, 'Wave Frequency')
            app.UIAxes_allFrequency.XTick = [];
            app.UIAxes_allFrequency.YTick = [];
            app.UIAxes_allIWI = uiaxes(app.allWavesTab);
            title(app.UIAxes_allIWI, 'Inter-Wave Interval')
            app.UIAxes_allIWI.XTick = [];
            app.UIAxes_allIWI.YTick = [];
            app.allPropDirPanel = uipanel(app.allWavesTab);
            app.allPropDirPanel.Title = 'Panel3';
            app.nasalValue = uilabel(app.allWavesTab);
            app.nasalValue.Text = 'Nasal Initiation =';
            app.temporalValue = uilabel(app.allWavesTab);
            app.temporalValue.Text = 'Temporal Initiation =';
            app.midlineValue = uilabel(app.allWavesTab);
            app.midlineValue.Text = 'Midline Initiation =';
            app.downSampleSpinner2 = uispinner(app.allWavesTab);
            app.downSampleSpinner2.ValueChangedFcn = createCallbackFcn(app, @downSampleSpinner2ValueChanged, true);
            app.SmoothLevelSpinnerLabel = uilabel(app.allWavesTab);
            app.SmoothLevelSpinnerLabel.Text = 'Smooth Level';
            app.browseFilesButton = uibutton(app.UIFigure, 'push');
            app.browseFilesButton.ButtonPushedFcn = createCallbackFcn(app, @browseFilesButtonPushed, true);
            app.browseFilesButton.Icon = fullfile(pathToMLAPP, 'icon-mat-folder-edb120.png');
            app.browseFilesButton.Text = 'Browse';
            app.fileNameLabel = uilabel(app.UIFigure);
            app.fileNameLabel.Text = '';
            app.AquisitionRateEditFieldLabel = uilabel(app.UIFigure);
            app.AquisitionRateEditFieldLabel.Text = 'Aquisition Rate';
            app.aquisitionRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.aquisitionRateEditField.Value = 20;
            app.kHzLabel = uilabel(app.UIFigure);
            app.kHzLabel.Text = 'kHz';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.RecordingLengthEditFieldLabel = uilabel(app.UIFigure);
            app.RecordingLengthEditFieldLabel.Text = 'Recording Length';
            app.recordingLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.recordingLengthEditField.Value = 600;
            app.loadDataButton = uibutton(app.UIFigure, 'push');
            app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButtonPushed, true);
            app.loadDataButton.Icon = fullfile(pathToMLAPP, 'icon-mat-file-upload.png');
            app.loadDataButton.Text = 'Upload';
            app.appLogo = uiimage(app.UIFigure);
            app.appLogo.ImageSource = fullfile(pathToMLAPP, 'appLogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveAnalysisApp_v7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end